classdef alignmentGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        TipGaugeLabel     matlab.ui.control.Label
        TipGauge          matlab.ui.control.SemicircularGauge
        TiltGaugeLabel    matlab.ui.control.Label
        TiltGauge         matlab.ui.control.SemicircularGauge
        XYLampLabel       matlab.ui.control.Label
        XYLamp            matlab.ui.control.Lamp
        TipTiltLampLabel  matlab.ui.control.Label
        TipTiltLamp       matlab.ui.control.Lamp
        XGauge            matlab.ui.control.LinearGauge
        YGauge            matlab.ui.control.LinearGauge
        OKButton          matlab.ui.control.Button
        CancelButton      matlab.ui.control.Button
        TipValue          matlab.ui.control.Label
        TiltValue         matlab.ui.control.Label
        XValue            matlab.ui.control.Label
        YValue            matlab.ui.control.Label
        MotorsPanel       matlab.ui.container.Panel
        TipHome           matlab.ui.control.Button
        TiltHome          matlab.ui.control.Button
        TipMotorValue     matlab.ui.control.Label
        TiltMotorValue    matlab.ui.control.Label
        mmLabel           matlab.ui.control.Label
        mmLabel_2         matlab.ui.control.Label
        TipMotorName      matlab.ui.control.Label
        TiltMotorName     matlab.ui.control.Label
        TipPlus           matlab.ui.control.Button
        TipMinus          matlab.ui.control.Button
        TiltMinus         matlab.ui.control.Button
        TiltPlus          matlab.ui.control.Button
        EditField         matlab.ui.control.NumericEditField
        AutoAlignButton   matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        Message           matlab.ui.control.Label
        FluxGauge         matlab.ui.control.LinearGauge
        FluxLabel         matlab.ui.control.Label
        FluxLampLabel     matlab.ui.control.Label
        FluxLamp          matlab.ui.control.Lamp
        State             matlab.ui.control.DiscreteKnob
    end


    properties (Access = public)
        appTimer; % timer to refresh the app
        bench;
        callbacks; % callback function cell array executed after alignment
        
        state = 'Phase'; % Description
        stopTimer = 0;
        stopApp = 0;
    end

    methods (Access = public)
    
        function refresh(app, ~, ~, ~)
            bench = app.bench;
            %set(0, 'currentfigure', app.UIFigure);
            %set(app.UIFigure, 'currentAxes', app.UIAxes);
           
            switch app.state
                case 'Off'
                     app.Message.Text = 'Turned Off';
                     app.Message.FontColor = [1 0 0];
                     cla(app.UIAxes);
                     text(app.UIAxes, 0.5, 0.5, 'Turned Off', 'Units','normalized', 'FontSize',24,'Color','red', 'HorizontalAlignment', 'center'); 
                     app.OKButton.Enable = 'Off'; % turn ok button off so user cannot click ok and run callback task
                     app.runCheck;
                     return
                case 'Img'
                    app.Message.Text = 'Raw alignment';
                    app.Message.FontColor = [1 0 0];
                    cla(app.UIAxes);
                    text(app.UIAxes, 0.5, 0.5, 'Put wave front pinhole look at pop up rtd', 'Units','normalized', 'FontSize',18,'Color','red', 'HorizontalAlignment', 'center');
                    app.OKButton.Enable = 'Off'; % turn ok button off so user cannot click ok and run callback task
                    flux = naomi.measure.flux(app.bench);
                    app.FluxGauge.Value = flux;
                    if app.bench.has('gimbal')
                        app.AutoAlignButton.Enable = 'off';
                        app.StopButton.Enable = 'off';
                        app.TipPlus.Enable = 'on';
                        app.TipMinus.Enable = 'on';
                        app.TiltPlus.Enable = 'on';
                        app.TiltMinus.Enable = 'on';
                        app.TipHome.Enable = 'on';
                        app.TiltHome.Enable = 'on';
                    else
                       app.TiltHome.Enable = 'off';
                       app.TipHome.Enable = 'off';
                       app.TipMinus.Enable = 'off';
                       app.TipPlus.Enable = 'off';
                       app.TiltMinus.Enable = 'off';
                       app.TiltPlus.Enable = 'off';
                       app.AutoAlignButton.Enable = 'off'; 
                    end
                    
                    app.runCheck;
                    return
            end
            app.OKButton.Enable = 'On';
            if app.bench.has('gimbal')
                if app.bench.isProcessRunning('alignAutoTipTilt')
                   app.AutoAlignButton.Enable = 'off';
                   app.StopButton.Enable = 'on';
                   app.TipPlus.Enable = 'off';
                   app.TipMinus.Enable = 'off';
                   app.TiltPlus.Enable = 'off';
                   app.TiltMinus.Enable = 'off';
                   app.TipHome.Enable = 'off';
                   app.TiltHome.Enable = 'off';
               else
                   app.AutoAlignButton.Enable = 'on';
                   app.StopButton.Enable = 'off';
                   app.TipPlus.Enable = 'on';
                   app.TipMinus.Enable = 'on';
                   app.TiltPlus.Enable = 'on';
                   app.TiltMinus.Enable = 'on';
                   app.TipHome.Enable = 'on';
                   app.TiltHome.Enable = 'on';
               end   
            else
               app.TiltHome.Enable = 'off';
               app.TipHome.Enable = 'off';
               app.TipMinus.Enable = 'off';
               app.TipPlus.Enable = 'off';
               app.TiltMinus.Enable = 'off';
               app.TiltPlus.Enable = 'off';
               app.AutoAlignButton.Enable = 'off'; 
            end
            
            if ~app.bench.has('wfs')
                app.Message.Text = 'The wave front sensor is not started. Start it';
                app.Message.FontColor = [1 0 0];
                text(app.UIAxes, 0.5, 0.5, 'Wave Front not Started', 'FontSize',14,'Color','red', 'HorizontalAlignment', 'center');
                app.runCheck;
                return 
            else
                
            end
            flux = naomi.measure.flux(app.bench);
            app.FluxGauge.Value = flux;
            if ~app.bench.checkFlux(flux)
                app.Message.Text = 'Flux is too low to measure a phase correctly. Adjust flux first';
                app.Message.FontColor = [1 0 0];
                text(app.UIAxes, 0.5, 0.5, 'Flux is too low', 'FontSize',14,'Color','red', 'HorizontalAlignment', 'center');
                app.runCheck;
                return 
            end
            
            app.Message.Text = '';
            app.Message.FontColor = [0 0 0];
            
            tiltThreshold = bench.config.pupillTipTiltThresholdManual;
	    pupThreshold  = bench.config.pupillCenteringThreshold;
            %%
            % one phase, no-tiptilt filter , no-reference substraction, no mask
            
            phi = naomi.measure.phase(bench, 1, 0, 0, 0);
            if strcmp(app.state, 'Phase')
                imagesc(app.UIAxes, phi);
                hold(app.UIAxes, 'on');
                alpha = linspace(0,2*pi, 50);
                r1 = bench.meter2pixel(bench.config.fullPupillDiameter/2);
                mask = bench.ztcParameters([], 'pixel');
                r2 = mask{1}/2;
                r0 = bench.nSubAperture/2;
                plot(app.UIAxes,r1*cos(alpha)+r0, r1*sin(alpha)+r0, 'k:');
                plot(app.UIAxes,r2*cos(alpha)+r0, r2*sin(alpha)+r0, 'k:');
                hold(app.UIAxes, 'off');
            end
            
            %xlabel(app.UIAxes,''); ylabel(app.UIAxes,'');
            %imagesc(phi);
            [nX,nY] = size(phi);
            xlim(app.UIAxes, [1,nX]);
            ylim(app.UIAxes, [1,nY]);
            %plot(app.UIAxes, [1,2,3,4,3,6]);
            [dX,dY,dTip,dTilt,dFoc] = naomi.make.missalignment(bench, phi);
            title(app.UIAxes,{ datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF'),
               sprintf('x=%.3fmm  y=%.3fmm  tip=%.2f  tilt=%.2f',...
                       dX*1e3, dY*1e3, dTip, dTilt)
               });

           app.TipGauge.Value = dTip;
           app.TiltGauge.Value = dTilt;
           app.XGauge.Value = dX*1000;
           app.YGauge.Value = dY*1000;
           app.XValue.Text = sprintf('%.4f',dX*1000);
           app.YValue.Text = sprintf('%.4f',dY*1000);
           app.TipValue.Text  = sprintf('%.4f',dTip);
           app.TiltValue.Text = sprintf('%.4f',dTilt);
           %if ((dX^2+dY^2)<= pupThreshold^2)
           test =0;
           if ((abs(dX)<= pupThreshold) && (abs(dY)<= pupThreshold))
               app.XYLamp.Color = 'green';
               test = test + 1;
           elseif strcmp(app.bench.config.dmId, naomi.KEYS.REFERENCE_MIRROR)
               % allways ok with REFERENCE MiRROR
               app.XYLamp.Color = 'green';
               %app.XYLamp.Label = 'X/Y (Allways ok with Reference Mirror)';
               app.XGauge.Visible = 'off';
               app.YGauge.Visible = 'off';
               test = test + 1;
               
           else
               app.XYLamp.Color = 'red';
               app.XGauge.Visible = 'on';
               app.YGauge.Visible = 'on';
           end
           %if ((dTip^2+dTilt^2) <= tiltThreshold^2)
           if (abs(dTip) <= tiltThreshold) && (abs(dTilt) <= tiltThreshold)
               app.TipTiltLamp.Color = 'green';
               test = test + 1;
           else
               app.TipTiltLamp.Color = 'red';
           end
           if flux>=app.bench.config.bestMinFlux && flux<=app.bench.config.bestMaxFlux
               app.FluxLamp.Color='green';
           else
               app.FluxLamp.Color='red';
           end
           
%            if test==2
%                app.OKButton.Enable = 'on';
%            else
%                app.OKButton.Enable = 'off';
%            end
           
           if app.bench.has('gimbal')
             app.TipMotorValue.Text  = sprintf('%.4f',app.bench.gimbal.getPos(app.bench.axisMotor('tip')));
             app.TiltMotorValue.Text = sprintf('%.4f',app.bench.gimbal.getPos(app.bench.axisMotor('tilt')));
           end
           app.runCheck;
             
        end
        function runCheck(app)
            if app.stopTimer || app.stopApp
               if isvalid(app.appTimer)
                   if strcmp(get(app.appTimer,'Running'), 'on')
                       stop(app.appTimer);
                   end
               end
           end
           if app.stopApp
               delete(app);
           end
        end
        function moveAxis(app, order, valueMumRms)
            s = app.bench.axisSign(order);
            axis = app.bench.axisMotor(order);
            app.bench.gimbal.moveByArcsec(axis, s * valueMumRms*4/2*1e-6/app.bench.config.fullPupillDiameter / 5e-6);
        end
        
        function togglePhase(app)
                naomi.task.turnRawAlignmentOff;
                if isvalid(app.appTimer) 
                    if strcmp(get(app.appTimer, 'Running'), 'off')
                        app.stopTimer = 0;
                        start(app.appTimer);
                    end
                else
                    app.appTimer = timer('Period',0.7,...
                                         'ExecutionMode', 'fixedSpacing', ...
                                         'TasksToExecute', Inf);
            
                    app.appTimer.TimerFcn = @app.refresh;
                    app.stopTimer = 0;
                    start(app.appTimer);
                end   
                app.state = 'Phase';
                app.State.Value = 'Phase';
        end
        
        function toggleOff(app)
                naomi.task.turnRawAlignmentOff;
                app.state = 'Off';
                app.stopTimer = 1;
                app.State.Value = 'Off';
                app.refresh();
%             if nargin<2; refresh=1;end
%                 naomi.task.turnRawAlignmentOff;
%                 if isvalid(app.appTimer)
%                      if strcmp(get(app.appTimer, 'Running'), 'on')
%                         stop(app.appTimer);
%                      end
%                 end
%                 app.state = 'Off';
%                 app.stopTimer = 1;
%                 if refresh
%                     app.State.Value = 'Off';
%                     app.refresh();
%                 end
        end
        function toggleImg(app)
                  app.state = 'Img';
                  app.stopTimer = 0; % do not stop timer for flux monitoring 
                  naomi.task.turnRawAlignmentOn;
                  app.refresh();
                  
%                 if isvalid(app.appTimer)
%                      if strcmp(get(app.appTimer, 'Running'), 'on')
%                         stop(app.appTimer);
%                      end
%                 end
%                 app.stopTimer = 1;
%                 app.state = 'Raw';       
%                 app.State.Value = 'Raw';
%                 naomi.task.turnRawAlignmentOn;
%                 app.refresh();
%                 pause(1.0); % live time to running timer to finish 
%                 app.refresh();
               
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench, callbacks)
            if nargin>2
                app.callbacks = callbacks;
            else
                app.callbacks = {};
            end
            app.bench = bench;
            app.appTimer = timer('Period',0.7,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.appTimer.TimerFcn = @app.refresh;
            
            app.UIFigure.Name = naomi.KEYS.G_ALIGNMENT;
           
            
            tiltThreshold = bench.config.pupillTipTiltThresholdManual;
	    pupThreshold = bench.config.pupillCenteringThreshold*1000;
            
            app.TipGauge.ScaleColors = {'red', 'green', 'red'};
            app.TipGauge.ScaleColorLimits = [-10 -tiltThreshold ; -tiltThreshold tiltThreshold ; tiltThreshold 10];
            app.TiltGauge.ScaleColors = {'red', 'green', 'red'};
            app.TiltGauge.ScaleColorLimits = [-10 -tiltThreshold ; -tiltThreshold tiltThreshold ; tiltThreshold 10];
            
            app.XGauge.ScaleColors = {'red', 'green', 'red'};
            app.XGauge.ScaleColorLimits = [-10 -pupThreshold ; -pupThreshold pupThreshold ; pupThreshold 10];
            app.YGauge.ScaleColors = {'red', 'green', 'red'};
            app.YGauge.ScaleColorLimits = [-10 -pupThreshold ; -pupThreshold pupThreshold ; pupThreshold 10];
            
            app.FluxGauge.ScaleColors = {'red', 'yellow', 'green', 'red'};
            app.FluxGauge.ScaleColorLimits = [0 app.bench.config.minFlux; app.bench.config.minFlux app.bench.config.bestMinFlux;...
                                                app.bench.config.bestMinFlux app.bench.config.bestMaxFlux;  app.bench.config.bestMaxFlux 1.0]; 
            
            switch app.bench.config.rXOrder
                case app.bench.config.TIP
                    app.TipMotorName.Text = 'rX';
                    app.TiltMotorName.Text = 'rY';
                otherwise
                    app.TipMotorName.Text = 'rY';
                    app.TiltMotorName.Text = 'rX';
            end
            start(app.appTimer);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            stop(app.appTimer);
            app.bench.killProcess('alignAutoTipTilt');
            for iCallback=1:length(app.callbacks)
                f = app.callbacks{iCallback};
                f();
            end
            %naomi.task.afterAligment(app.bench);
            close(app.UIFigure);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             
             
             close(app.UIFigure);
        end

        % Button pushed function: TipPlus
        function TipPlusButtonPushed(app, event)
            app.moveAxis('tip', app.EditField.Value);
        end

        % Button pushed function: TipMinus
        function TipMinusButtonPushed(app, event)
            app.moveAxis('tip', -app.EditField.Value);       
        end

        % Button pushed function: TiltPlus
        function TiltPlusButtonPushed(app, event)
            app.moveAxis('tilt', app.EditField.Value);     
        end

        % Button pushed function: TiltMinus
        function TiltMinusButtonPushed(app, event)
            app.moveAxis('tilt', -app.EditField.Value);        
        end

        % Button pushed function: TipHome
        function TipHomeButtonPushed(app, event)
            if strcmp(app.bench.config.rXOrder,'tip')
                app.bench.gimbal.moveToZero('rX');
            else
                app.bench.gimbal.moveToZero('rY');
            end
        end

        % Button pushed function: TiltHome
        function TiltHomeButtonPushed(app, event)
            if strcmp(app.bench.config.rXOrder,'tilt')
                app.bench.gimbal.moveToZero('rX');
            else
                app.bench.gimbal.moveToZero('rY');
            end
        end

        % Button pushed function: AutoAlignButton
        function AutoAlignButtonPushed(app, event)
            % start the process here to avoid plinking buttons
            app.bench.registerProcess('alignAutoTipTilt');
            app.AutoAlignButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            app.TipPlus.Enable = 'off';
            app.TipMinus.Enable = 'off';
            app.TiltPlus.Enable = 'off';
            app.TiltMinus.Enable = 'off';
            app.TipHome.Enable = 'off';
            app.TiltHome.Enable = 'off';
            naomi.action.alignAutoTipTilt(app.bench);
            %start(app.alignTimer);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.bench.killProcess('alignAutoTipTilt');
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.bench.killProcess('alignAutoTipTilt');
            app.stopApp = 1; 
            
            app.toggleOff;
            
            
%             pause(0.2);
%             app.toggleOff(0);
%             pause(1.0);
%             delete(app.appTimer);
%             
%             delete(app)
            
        end

        % Callback function
        function OnOffSwitchValueChanged(app, event)
            value = app.OnOffSwitch.Value;
            disp(value);
            if strcmp(value, 'Off')
                app.toggleOff;
            else
                app.toggleOn;
            end
        end

        % Callback function
        function RawAlignmentSwitchValueChanged(app, event)
            value = app.RawAlignmentSwitch.Value;
             if strcmp(value, 'Off')
                naomi.task.turnRawAlignmentOff(app.bench);
                app.toggleOn;
                
             else
                 app.toggleOff;
                 naomi.task.turnRawAlignmentOn(app.bench);
                
                
             end
            
            
        end

        % Value changed function: State
        function StateValueChanged(app, event)
            value = app.State.Value;
            switch value
                case 'Off'
                    app.toggleOff;
                case 'Phase'
                    
                    app.togglePhase;
                case 'Img'
                    app.toggleImg;        
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Alignment';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.TipGaugeLabel = uilabel(app.UIFigure);
            app.TipGaugeLabel.Text = 'Tip';
            app.TipGauge = uigauge(app.UIFigure, 'semicircular');
            app.TipGauge.Limits = [-3 3];
            app.TiltGaugeLabel = uilabel(app.UIFigure);
            app.TiltGaugeLabel.Text = 'Tilt';
            app.TiltGauge = uigauge(app.UIFigure, 'semicircular');
            app.TiltGauge.Limits = [-3 3];
            app.XYLampLabel = uilabel(app.UIFigure);
            app.XYLampLabel.Text = 'X/Y';
            app.XYLamp = uilamp(app.UIFigure);
            app.TipTiltLampLabel = uilabel(app.UIFigure);
            app.TipTiltLampLabel.Text = 'TipTilt';
            app.TipTiltLamp = uilamp(app.UIFigure);
            app.XGauge = uigauge(app.UIFigure, 'linear');
            app.XGauge.Limits = [-0.25 0.25];
            app.XGauge.MajorTicks = [-0.25 -0.15 -0.05 0.05 0.15 0.25];
            app.YGauge = uigauge(app.UIFigure, 'linear');
            app.YGauge.Limits = [-0.25 0.25];
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TipValue = uilabel(app.UIFigure);
            app.TipValue.Text = '0.0';
            app.TiltValue = uilabel(app.UIFigure);
            app.TiltValue.Text = '0.0';
            app.XValue = uilabel(app.UIFigure);
            app.XValue.Text = '0.0';
            app.YValue = uilabel(app.UIFigure);
            app.YValue.Text = '0.0';
            app.MotorsPanel = uipanel(app.UIFigure);
            app.MotorsPanel.Title = 'Motors';
            app.TipHome = uibutton(app.MotorsPanel, 'push');
            app.TipHome.ButtonPushedFcn = createCallbackFcn(app, @TipHomeButtonPushed, true);
            app.TipHome.Text = 'Home';
            app.TiltHome = uibutton(app.MotorsPanel, 'push');
            app.TiltHome.ButtonPushedFcn = createCallbackFcn(app, @TiltHomeButtonPushed, true);
            app.TiltHome.Text = 'Home';
            app.TipMotorValue = uilabel(app.MotorsPanel);
            app.TipMotorValue.Text = '0.0';
            app.TiltMotorValue = uilabel(app.MotorsPanel);
            app.TiltMotorValue.Text = '0.0';
            app.mmLabel = uilabel(app.MotorsPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.MotorsPanel);
            app.mmLabel_2.Text = 'mm';
            app.TipMotorName = uilabel(app.MotorsPanel);
            app.TipMotorName.Text = '-';
            app.TiltMotorName = uilabel(app.MotorsPanel);
            app.TiltMotorName.Text = '-';
            app.TipPlus = uibutton(app.UIFigure, 'push');
            app.TipPlus.ButtonPushedFcn = createCallbackFcn(app, @TipPlusButtonPushed, true);
            app.TipPlus.Text = '+';
            app.TipMinus = uibutton(app.UIFigure, 'push');
            app.TipMinus.ButtonPushedFcn = createCallbackFcn(app, @TipMinusButtonPushed, true);
            app.TipMinus.Text = '-';
            app.TiltMinus = uibutton(app.UIFigure, 'push');
            app.TiltMinus.ButtonPushedFcn = createCallbackFcn(app, @TiltMinusButtonPushed, true);
            app.TiltMinus.Text = '-';
            app.TiltPlus = uibutton(app.UIFigure, 'push');
            app.TiltPlus.ButtonPushedFcn = createCallbackFcn(app, @TiltPlusButtonPushed, true);
            app.TiltPlus.Text = '+';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [0.001 50];
            app.EditField.Value = 0.1;
            app.AutoAlignButton = uibutton(app.UIFigure, 'push');
            app.AutoAlignButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAlignButtonPushed, true);
            app.AutoAlignButton.Text = 'Auto Align';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.Message = uilabel(app.UIFigure);
            app.Message.Text = 'Messages';
            app.FluxGauge = uigauge(app.UIFigure, 'linear');
            app.FluxGauge.Limits = [0 1];
            app.FluxLabel = uilabel(app.UIFigure);
            app.FluxLabel.Text = 'Flux';
            app.FluxLampLabel = uilabel(app.UIFigure);
            app.FluxLampLabel.Text = 'Flux';
            app.FluxLamp = uilamp(app.UIFigure);
            app.State = uiknob(app.UIFigure, 'discrete');
            app.State.Items = {'Off', 'Phase', 'Img'};
            app.State.ValueChangedFcn = createCallbackFcn(app, @StateValueChanged, true);
            app.State.Value = 'Phase';
        end
    end

    methods (Access = public)

        % Construct app
        function app = alignmentGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end