classdef calibGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationUIFigure             matlab.ui.Figure
        WithTheReferencemirrorPanel     matlab.ui.container.Panel
        FirstStepLabel                  matlab.ui.control.Label
        AlignReferenceMirrorAndrecordaPhaseReferenceButton  matlab.ui.control.Button
        AlignRefStatus                  matlab.ui.control.Label
        WithTheDMPanel                  matlab.ui.container.Panel
        LoadaPhaseReferenceButton       matlab.ui.control.Button
        SecondStepLabel                 matlab.ui.control.Label
        AlignDMButton                   matlab.ui.control.Button
        SendbenchtoCalibrationtemperatureButton  matlab.ui.control.Button
        SecondStepLabel_3               matlab.ui.control.Label
        CheckDefaultInteractionMatrixButton  matlab.ui.control.Button
        SecondStepLabel_4               matlab.ui.control.Label
        MakenewInteractionMatrixButton  matlab.ui.control.Button
        SecondStepLabel_5               matlab.ui.control.Label
        CheckNewMatrixButton            matlab.ui.control.Button
        SecondStepLabel_6               matlab.ui.control.Label
        CreatePDFReportButton           matlab.ui.control.Button
        SendBenchatambienttemperatureButton  matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        SecondStepLabel_2               matlab.ui.control.Label
        SecondStepLabel_7               matlab.ui.control.Label
        SecondStepLabel_8               matlab.ui.control.Label
        DoALLButton                     matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        WhentemperaturereachthecalibrationtemperatureLabel  matlab.ui.control.Label
        ThisshouldbethefilecreatedwiththealigneddummythesamedayLabel  matlab.ui.control.Label
        RefMirrorStatus                 matlab.ui.control.Label
        AlignDmStatus                   matlab.ui.control.Label
    end


    properties (Access = public)
        bench; % Description
        okColor = [0 .5 0];
        notOkColor = [1.0 0.0 0.0];
        listeners = {};
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            if ~app.bench.isAligned
                app.AlignRefStatus.Text = 'Not Aligned';
                app.AlignRefStatus.FontColor = app.notOkColor;
            else
                app.AlignRefStatus.Text = sprintf('Pupill Center : x=%.3f y=%.3f pixel', app.bench.xPupillCenter, app.bench.yPupillCenter);
                app.AlignRefStatus.FontColor = app.okColor;
            end
            if app.bench.isDm
                if app.bench.isPhaseReferenced
                    app.RefMirrorStatus.Text = sprintf('Phase Reference loaded: %s',app.bench.phaseReferenceData.shortInfo);
                    app.RefMirrorStatus.FontColor = app.okColor;
                else
                    app.RefMirrorStatus.Text = 'No phase reference loaded';
                    app.RefMirrorStatus.FontColor = app.notOkColor;
                end
                if app.bench.isCentered
                    app.AlignDmStatus.Text = sprintf('Dm center at : x=%.3f y=%.3f pixel', app.bench.xCenter, app.bench.yCenter);
                    app.AlignDmStatus.FontColor = app.okColor;
                else
                    app.AlignDmStatus.Text = 'DM Not Aligned';
                    app.AlignDmStatus.FontColor = app.notOkColor;
                end
            else
                app.RefMirrorStatus.Text = 'Not in DM mode';
                app.RefMirrorStatus.FontColor = app.notOkColor;
            end
        end
        
        function setListeners(app)
            app.clearListeners; 
            app.listeners = {...
                listener(app.bench, 'phaseReferenceData', 'PostSet', @app.refresh),...
                listener(app.bench, 'measuredXpixelScale', 'PostSet', @app.refresh),...
                listener(app.bench, 'measuredXcenter', 'PostSet', @app.refresh),...
                listener(app.bench, 'measuredXpupillCenter', 'PostSet', @app.refresh)};
        end
        function clearListeners(app)
            for i=1:length(app.listeners)
                delete(app.listeners{i});
            end
            app.listeners = {}; 
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            app.bench = bench;
            app.refresh();
            app.setListeners;
        end

        % Button pushed function: 
        % AlignReferenceMirrorAndrecordaPhaseReferenceButton
        function AlignReferenceMirrorAndrecordaPhaseReferenceButtonPushed(app, event)
            naomi.task.align(app.bench);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.CalibrationUIFigure);
        end

        % Button pushed function: LoadaPhaseReferenceButton
        function LoadaPhaseReferenceButtonPushed(app, event)
            naomi.config.phaseReference(app.bench);
        end

        % Button pushed function: AlignDMButton
        function AlignDMButtonPushed(app, event)
            naomi.task.align(app.bench);
        end

        % Button pushed function: 
        % SendbenchtoCalibrationtemperatureButton
        function SendbenchtoCalibrationtemperatureButtonPushed(app, event)
            if app.bench.has('environment')
                app.bench.environment.goToCalib;
            end
            naomi.task.openEnvironmentPanel(app.bench);
        end

        % Button pushed function: DoALLButton
        function DoALLButtonPushed(app, event)
            naomi.task.calibrateAndValidate(app.bench);
        end

        % Button pushed function: MakenewInteractionMatrixButton
        function MakenewInteractionMatrixButtonPushed(app, event)
            naomi.task.calibrate(app.bench);
        end

        % Button pushed function: CheckNewMatrixButton
        function CheckNewMatrixButtonPushed(app, event)
            naomi.task.validate(app.bench);
        end

        % Button pushed function: SendBenchatambienttemperatureButton
        function SendBenchatambienttemperatureButtonPushed(app, event)
            if app.bench.has('environment')
                app.bench.environment.goToEmbiant;
                naomi.task.openEnvironmentPanel(app.bench);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationUIFigure = uifigure;
            app.CalibrationUIFigure.Name = 'Calibration';
            app.WithTheReferencemirrorPanel = uipanel(app.CalibrationUIFigure);
            app.WithTheReferencemirrorPanel.Title = 'With The Reference mirror';
            app.FirstStepLabel = uilabel(app.WithTheReferencemirrorPanel);
            app.FirstStepLabel.Text = '1. Take a bench wavefront reference  with the Reference mirror inside the bench [1 minute]';
            app.AlignReferenceMirrorAndrecordaPhaseReferenceButton = uibutton(app.WithTheReferencemirrorPanel, 'push');
            app.AlignReferenceMirrorAndrecordaPhaseReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AlignReferenceMirrorAndrecordaPhaseReferenceButtonPushed, true);
            app.AlignReferenceMirrorAndrecordaPhaseReferenceButton.Text = 'Align Reference Mirror And record a Phase Reference';
            app.AlignRefStatus = uilabel(app.WithTheReferencemirrorPanel);
            app.AlignRefStatus.Text = '-';
            app.WithTheDMPanel = uipanel(app.CalibrationUIFigure);
            app.WithTheDMPanel.Title = 'With The DM';
            app.LoadaPhaseReferenceButton = uibutton(app.WithTheDMPanel, 'push');
            app.LoadaPhaseReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaPhaseReferenceButtonPushed, true);
            app.LoadaPhaseReferenceButton.Text = 'Load a Phase Reference';
            app.SecondStepLabel = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel.Text = '2. Align DM at ambient temperature [1minute]';
            app.AlignDMButton = uibutton(app.WithTheDMPanel, 'push');
            app.AlignDMButton.ButtonPushedFcn = createCallbackFcn(app, @AlignDMButtonPushed, true);
            app.AlignDMButton.Text = 'Align DM';
            app.SendbenchtoCalibrationtemperatureButton = uibutton(app.WithTheDMPanel, 'push');
            app.SendbenchtoCalibrationtemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @SendbenchtoCalibrationtemperatureButtonPushed, true);
            app.SendbenchtoCalibrationtemperatureButton.Text = 'Send bench to Calibration temperature ';
            app.SecondStepLabel_3 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_3.Enable = 'off';
            app.SecondStepLabel_3.Text = '4.1 Check The Default Interaction Matrix [5 minutes] ';
            app.CheckDefaultInteractionMatrixButton = uibutton(app.WithTheDMPanel, 'push');
            app.CheckDefaultInteractionMatrixButton.Enable = 'off';
            app.CheckDefaultInteractionMatrixButton.Text = 'Check Default Interaction Matrix';
            app.SecondStepLabel_4 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_4.Text = '4.2 Make a new interaction matrix and bias  [0.5 hours] ';
            app.MakenewInteractionMatrixButton = uibutton(app.WithTheDMPanel, 'push');
            app.MakenewInteractionMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @MakenewInteractionMatrixButtonPushed, true);
            app.MakenewInteractionMatrixButton.Text = 'Make new Interaction Matrix';
            app.SecondStepLabel_5 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_5.Text = '4.2 Check the new Interaction Matrix [5 minutes]';
            app.CheckNewMatrixButton = uibutton(app.WithTheDMPanel, 'push');
            app.CheckNewMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CheckNewMatrixButtonPushed, true);
            app.CheckNewMatrixButton.Text = 'Check New Matrix';
            app.SecondStepLabel_6 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_6.Text = '5. Warm up and Create PDF report';
            app.CreatePDFReportButton = uibutton(app.WithTheDMPanel, 'push');
            app.CreatePDFReportButton.Enable = 'off';
            app.CreatePDFReportButton.Text = 'Create PDF Report';
            app.SendBenchatambienttemperatureButton = uibutton(app.WithTheDMPanel, 'push');
            app.SendBenchatambienttemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @SendBenchatambienttemperatureButtonPushed, true);
            app.SendBenchatambienttemperatureButton.Text = 'Send Bench at ambient temperature';
            app.CloseButton = uibutton(app.WithTheDMPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RefreshButton = uibutton(app.WithTheDMPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.SecondStepLabel_2 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_2.Text = '3. Start Temperature Regulation (reach 10 degree)  [~1.5 hour if ambient at 20 degree] ';
            app.SecondStepLabel_7 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_7.Text = '1. Load a Measured DUMMY  Phase Reference File :  WFREF_*.fits';
            app.SecondStepLabel_8 = uilabel(app.WithTheDMPanel);
            app.SecondStepLabel_8.Text = '4. Calibrate And Check Calibration [40 minutes]';
            app.DoALLButton = uibutton(app.WithTheDMPanel, 'push');
            app.DoALLButton.ButtonPushedFcn = createCallbackFcn(app, @DoALLButtonPushed, true);
            app.DoALLButton.Text = 'Do ALL';
            app.Label = uilabel(app.WithTheDMPanel);
            app.Label.Text = 'In the Environment calibration panel start with the mode to "Go To Calib Temperature" ';
            app.WhentemperaturereachthecalibrationtemperatureLabel = uilabel(app.WithTheDMPanel);
            app.WhentemperaturereachthecalibrationtemperatureLabel.Text = 'When temperature reach the calibration temperature set it to "Calibration" ';
            app.ThisshouldbethefilecreatedwiththealigneddummythesamedayLabel = uilabel(app.WithTheDMPanel);
            app.ThisshouldbethefilecreatedwiththealigneddummythesamedayLabel.Text = 'This should be the file created with the aligned dummy the same day ';
            app.RefMirrorStatus = uilabel(app.WithTheDMPanel);
            app.RefMirrorStatus.Text = '-';
            app.AlignDmStatus = uilabel(app.WithTheDMPanel);
            app.AlignDmStatus.Text = '-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = calibGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationUIFigure)
        end
    end
end