classdef environmentControlGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnvironmentControl           matlab.ui.Figure
        CloseButton                  matlab.ui.control.Button
        LiveValuePanel               matlab.ui.container.Panel
        CurrentValue                 matlab.ui.control.Label
        PeltierCurrentLabel          matlab.ui.control.Label
        Fan1Value                    matlab.ui.control.Label
        InnerFanLabel                matlab.ui.control.Label
        OuterFanLabel                matlab.ui.control.Label
        Fan2Value                    matlab.ui.control.Label
        InnerTempregulLabel          matlab.ui.control.Label
        OutValue                     matlab.ui.control.Label
        InValue                      matlab.ui.control.Label
        OuterTempLabel               matlab.ui.control.Label
        MirrorValue                  matlab.ui.control.Label
        MirrorTempLabel              matlab.ui.control.Label
        QSMValue                     matlab.ui.control.Label
        QSMTempLabel                 matlab.ui.control.Label
        SafeToOpenLabel              matlab.ui.control.Label
        SafeToOpen                   matlab.ui.control.Lamp
        ReadyForDMCalibrationLabel   matlab.ui.control.Label
        ReadyForDMCalibration        matlab.ui.control.Lamp
        SafeToOpenLegend             matlab.ui.control.TextArea
        ReadyForDMCalibrationLegend  matlab.ui.control.TextArea
        HumidityLabel                matlab.ui.control.Label
        HumidityValue                matlab.ui.control.Label
        ManualModeOnlyPanel          matlab.ui.container.Panel
        SetPointEditFieldLabel       matlab.ui.control.Label
        SetPoint                     matlab.ui.control.NumericEditField
        InnerFanEditFieldLabel       matlab.ui.control.Label
        InnerFan                     matlab.ui.control.NumericEditField
        OuterFanLabel_2              matlab.ui.control.Label
        OuterFan                     matlab.ui.control.NumericEditField
        CLabel                       matlab.ui.control.Label
        VLabel                       matlab.ui.control.Label
        VLabel_2                     matlab.ui.control.Label
        SetButton                    matlab.ui.control.Button
        RetrieveButton               matlab.ui.control.Button
        ModeSelectionDropDownLabel   matlab.ui.control.Label
        ModeSelection                matlab.ui.control.DropDown
        CurrentMode                  matlab.ui.control.Label
        LiveGraph                    matlab.ui.container.Panel
        UIAxesCURRENT                matlab.ui.control.UIAxes
        UIAxesFAN                    matlab.ui.control.UIAxes
        UIAxesTemp                   matlab.ui.control.UIAxes
        PlotButton                   matlab.ui.control.Button
        SetMode                      matlab.ui.control.Button
    end


    properties (Access = public)
        environment; % naomi.objects.Environment object
        buffer; % naomi.objects.EnvironmentBuffer object
        
        firstTimeManual = 1;
        
        stateNameList;
        stateList;
        stateName2state;
        state2stateName;
        listeners;
        
    end


    methods (Access = public)
        
        function updateBuffer(app, varargin)
            app.environment.updateControl();
            app.buffer.update(app.environment);
        end
        
        function refresh(app, varargin)
            app.CurrentMode.Text = app.state2stateName(app.environment.getState());
            switch app.environment.getState()
                case app.environment.MANUAL
                    app.SetPoint.Enable = 'on';
                    app.InnerFan.Enable = 'on';
                    app.OuterFan.Enable = 'on';
                    app.SetButton.Enable = 'on';
                otherwise
                    app.SetPoint.Enable = 'off';
                    app.InnerFan.Enable = 'off';
                    app.OuterFan.Enable = 'off';
                    app.SetButton.Enable = 'off';
            end
            
            if ~app.buffer.index
                return 
            end
            
            
            app.buffer.plotAll( {app.UIAxesCURRENT,app.UIAxesFAN, app.UIAxesTemp});
            time = app.buffer.get('time');
            times = (time-time(1))*24*3600;
            hold(app.UIAxesTemp, 'on');
            plot(app.UIAxesTemp, [min(times), max(times)], [app.environment.calibTemperature,app.environment.calibTemperature], 'c-', 'DisplayName', 'Calibration temp. To reach');
            hold(app.UIAxesTemp, 'off');
            
            app.LiveGraph.Title = sprintf('%s -> %s',datestr(time(1), 'yyyy-mm-ddThh:MM:SS'), ...
                                     datestr(time(end), 'yyyy-mm-ddThh:MM:SS'));
                                          
            app.LiveValuePanel.Title = datestr(time(end), 'yyyy-mm-ddThh:MM:SS');
            fmt = '%.2f C';
            
            c = app.buffer.get('current');
            app.CurrentValue.Text = sprintf('%.2f A', c(end));
            f1 = app.buffer.get('fanIn');
            app.Fan1Value.Text = sprintf('%.2f V', f1(end));
            f2 = app.buffer.get('fanOut');
            app.Fan2Value.Text = sprintf('%.2f V', f2(end));
            t2 = app.buffer.get('tempOut');
            app.OutValue.Text = sprintf(fmt, t2(end));
            t1 = app.buffer.get('tempIn');
            app.InValue.Text = sprintf(fmt, t1(end));
            t5 = app.buffer.get('tempMirror');
            app.MirrorValue.Text = sprintf(fmt, t5(end));
            
            if abs(t5(end)-app.environment.calibTemperature)<= app.environment.calibDeltaTemp
                app.MirrorValue.BackgroundColor = [0.0 1.0 0.0];
                
            else
                app.MirrorValue.BackgroundColor = [1.0 0.0 0.0];
            end
            
            t6 = app.buffer.get('tempQSM');
            app.QSMValue.Text = sprintf(fmt, t6(end));
            
            rh = app.buffer.get('humidity');
            app.HumidityValue.Text = sprintf('%.1f%%', rh(end));
            
            [test, expl] = app.environment.isSafeToOpen;
            if test
                app.SafeToOpen.Color = [0.0 1.0 0.0];
            else
                app.SafeToOpen.Color = [1.0 0.0 0.0];
            end
            app.SafeToOpenLegend.Value = expl;
            
            [test, expl] = app.environment.isReadyToCalib;
            if test
                app.ReadyForDMCalibration.Color = [0.0 1.0 0.0];
            else
                app.ReadyForDMCalibration.Color = [1.0 0.0 0.0];
            end
            app.ReadyForDMCalibrationLegend.Value = expl;
            
                    
        end
    
        function setListeners(app)
            app.clearListeners;
            app.listeners = {listener(app.buffer, 'updateCounter', 'PostSet', @app.refresh)};
            
        end
    
        function clearListeners(app)
            
            for i=1:length(app.listeners)
                delete(app.listeners{i});
            end
            app.listeners = {};
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, environment, buffer)
            global naomiGlobalEnvironmentBuffer;
            if nargin<3
                if exist('naomiGlobalEnvironmentBuffer', 'var')
                    buffer = naomiGlobalEnvironmentBuffer;
                else
                    buffer = environment.createBuffer(10000,2500,1);
                end
            end
            app.environment = environment;
            app.buffer = buffer;
            % update temperature every 3 seconds
            app.buffer.startTimer(app.environment, 3); 


            % we do not need the maintain temperature mode in the list it is transparent for user
            app.stateNameList = {'Go To Calibration Temperature', ...%'Maintain Temperature', ...
                                'Calibration', 'Go To Ambient Temperature', ...
                                'Manual', 'Off'};
            app.stateList = {app.environment.GOTOCALIB, ...% app.environment.MAINTAIN, ...
                             app.environment.CALIB, app.environment.GOTOAMBIENT, ...
                             app.environment.MANUAL, app.environment.OFF};
            app.stateName2state = containers.Map(app.stateNameList, app.stateList);
            app.state2stateName = containers.Map(app.stateList, app.stateNameList);
            app.state2stateName(app.environment.MAINTAIN) = 'Go To Calibration Temperature (Maintain)';
            app.ModeSelection.Items = app.stateNameList;
            app.setListeners;
            
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Callback function
        function GoToCalibTemperatureButtonPushed(app, event)
            app.environment.goToCalib();
        end

        % Callback function
        function MaintainTemperatureCalibModeButtonPushed(app, event)
           app.environment.calib(); 
        end

        % Callback function
        function TurnOffButtonPushed(app, event)
           app.environment.turnOff();
        end

        % Close request function: EnvironmentControl
        function EnvironmentControlCloseRequest(app, event)
            app.clearListeners;
            % stop the timer when closing
            app.buffer.stopTimer;
            delete(app.EnvironmentControl);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.EnvironmentControl);
        end

        % Callback function
        function ManualButtonPushed(app, event)
            [tempRegul, fanIn, fanOut] = app.environment.manual();
            app.SetPoint.Value = tempRegul;
            app.InnerFan.Value = fanIn;
            app.OuterFan.Value = fanOut; 
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.environment.manualSet(app.SetPoint.Value, app.InnerFan.Value, app.OuterFan.Value);
        end

        % Callback function
        function GoToEmbiantTemperatureButtonPushed(app, event)
            app.environment.goToAmbient();
        end

        % Callback function
        function ModeSelectionValueChanged(app, event)
            value = app.ModeSelection.Value;
            app.environment.changeState(app.stateName2state(value));
        end

        % Button pushed function: RetrieveButton
        function RetrieveButtonPushed(app, event)
            [app.SetPoint.Value, app.InnerFan.Value, app.OuterFan.Value] = app.environment.manualRetrieve();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            f = naomi.plot.figure('environment');
            % bring it front 
            figure(f.Number);
            app.buffer.plotAll();
        end

        % Button pushed function: SetMode
        function SetModeButtonPushed(app, event)
            value = app.ModeSelection.Value;
            app.environment.changeState(app.stateName2state(value));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnvironmentControl = uifigure;
            app.EnvironmentControl.Name = 'Environment';
            app.EnvironmentControl.CloseRequestFcn = createCallbackFcn(app, @EnvironmentControlCloseRequest, true);
            app.CloseButton = uibutton(app.EnvironmentControl, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.LiveValuePanel = uipanel(app.EnvironmentControl);
            app.LiveValuePanel.Title = '0000-01-00T00:00:00';
            app.CurrentValue = uilabel(app.LiveValuePanel);
            app.CurrentValue.Text = '0.0';
            app.PeltierCurrentLabel = uilabel(app.LiveValuePanel);
            app.PeltierCurrentLabel.Text = 'Peltier Current';
            app.Fan1Value = uilabel(app.LiveValuePanel);
            app.Fan1Value.Text = '0.0';
            app.InnerFanLabel = uilabel(app.LiveValuePanel);
            app.InnerFanLabel.Text = 'Inner Fan';
            app.OuterFanLabel = uilabel(app.LiveValuePanel);
            app.OuterFanLabel.Text = 'Outer Fan';
            app.Fan2Value = uilabel(app.LiveValuePanel);
            app.Fan2Value.Text = '0.0';
            app.InnerTempregulLabel = uilabel(app.LiveValuePanel);
            app.InnerTempregulLabel.Text = 'Inner Temp (regul)';
            app.OutValue = uilabel(app.LiveValuePanel);
            app.OutValue.Text = '0.0';
            app.InValue = uilabel(app.LiveValuePanel);
            app.InValue.Text = '0.0';
            app.OuterTempLabel = uilabel(app.LiveValuePanel);
            app.OuterTempLabel.Text = 'Outer Temp.';
            app.MirrorValue = uilabel(app.LiveValuePanel);
            app.MirrorValue.Text = '0.0';
            app.MirrorTempLabel = uilabel(app.LiveValuePanel);
            app.MirrorTempLabel.Text = 'Mirror Temp.';
            app.QSMValue = uilabel(app.LiveValuePanel);
            app.QSMValue.Text = '0.0';
            app.QSMTempLabel = uilabel(app.LiveValuePanel);
            app.QSMTempLabel.Text = 'QSM Temp.';
            app.SafeToOpenLabel = uilabel(app.LiveValuePanel);
            app.SafeToOpenLabel.Text = 'Safe To Open ?';
            app.SafeToOpen = uilamp(app.LiveValuePanel);
            app.ReadyForDMCalibrationLabel = uilabel(app.LiveValuePanel);
            app.ReadyForDMCalibrationLabel.Text = 'Ready For DM Calibration ?';
            app.ReadyForDMCalibration = uilamp(app.LiveValuePanel);
            app.SafeToOpenLegend = uitextarea(app.LiveValuePanel);
            app.SafeToOpenLegend.Editable = 'off';
            app.ReadyForDMCalibrationLegend = uitextarea(app.LiveValuePanel);
            app.ReadyForDMCalibrationLegend.Editable = 'off';
            app.HumidityLabel = uilabel(app.LiveValuePanel);
            app.HumidityLabel.Text = 'Humidity';
            app.HumidityValue = uilabel(app.LiveValuePanel);
            app.HumidityValue.Text = '0.0';
            app.ManualModeOnlyPanel = uipanel(app.EnvironmentControl);
            app.ManualModeOnlyPanel.Title = 'Manual Mode Only';
            app.SetPointEditFieldLabel = uilabel(app.ManualModeOnlyPanel);
            app.SetPointEditFieldLabel.Text = 'Set Point';
            app.SetPoint = uieditfield(app.ManualModeOnlyPanel, 'numeric');
            app.SetPoint.Limits = [-5 40];
            app.InnerFanEditFieldLabel = uilabel(app.ManualModeOnlyPanel);
            app.InnerFanEditFieldLabel.Text = 'Inner Fan';
            app.InnerFan = uieditfield(app.ManualModeOnlyPanel, 'numeric');
            app.InnerFan.Limits = [-24 24];
            app.OuterFanLabel_2 = uilabel(app.ManualModeOnlyPanel);
            app.OuterFanLabel_2.Text = 'Outer Fan';
            app.OuterFan = uieditfield(app.ManualModeOnlyPanel, 'numeric');
            app.OuterFan.Limits = [-24 24];
            app.CLabel = uilabel(app.ManualModeOnlyPanel);
            app.CLabel.Text = 'C';
            app.VLabel = uilabel(app.ManualModeOnlyPanel);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.ManualModeOnlyPanel);
            app.VLabel_2.Text = 'V';
            app.SetButton = uibutton(app.ManualModeOnlyPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.RetrieveButton = uibutton(app.ManualModeOnlyPanel, 'push');
            app.RetrieveButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPushed, true);
            app.RetrieveButton.Text = 'Retrieve';
            app.ModeSelectionDropDownLabel = uilabel(app.EnvironmentControl);
            app.ModeSelectionDropDownLabel.Text = 'Mode Selection';
            app.ModeSelection = uidropdown(app.EnvironmentControl);
            app.ModeSelection.Items = {'Go To Calibration Temperature', 'Maintain Calibration Temperature', 'Calibrate', 'Manual', 'Off'};
            app.ModeSelection.Value = 'Go To Calibration Temperature';
            app.CurrentMode = uilabel(app.EnvironmentControl);
            app.CurrentMode.Text = 'Current Mode';
            app.LiveGraph = uipanel(app.EnvironmentControl);
            app.LiveGraph.Title = '0000-01-00T00:00:00 -> 0000-01-00T00:00:00';
            app.UIAxesCURRENT = uiaxes(app.LiveGraph);
            title(app.UIAxesCURRENT, 'Peltier Current')
            ylabel(app.UIAxesCURRENT, 'current')
            app.UIAxesFAN = uiaxes(app.LiveGraph);
            title(app.UIAxesFAN, 'Fans')
            ylabel(app.UIAxesFAN, 'Voltage')
            app.UIAxesTemp = uiaxes(app.LiveGraph);
            title(app.UIAxesTemp, 'Temperatures')
            xlabel(app.UIAxesTemp, 'Time (s)')
            ylabel(app.UIAxesTemp, 'Temp C')
            app.PlotButton = uibutton(app.LiveGraph, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = '';
            app.SetMode = uibutton(app.EnvironmentControl, 'push');
            app.SetMode.ButtonPushedFcn = createCallbackFcn(app, @SetModeButtonPushed, true);
            app.SetMode.Text = 'Set';
        end
    end

    methods (Access = public)

        % Construct app
        function app = environmentControlGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnvironmentControl)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnvironmentControl)
        end
    end
end