classdef ifmMeasurementGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConfigPanel              matlab.ui.container.Panel
        sLabel                   matlab.ui.control.Label
        MeasurementModeLabel     matlab.ui.control.Label
        MeasurementMode          matlab.ui.control.DropDown
        NPushPullEditFieldLabel  matlab.ui.control.Label
        NPushPull                matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        Amplitude                matlab.ui.control.NumericEditField
        NloopEditFieldLabel      matlab.ui.control.Label
        Nloop                    matlab.ui.control.NumericEditField
        PauseEditFieldLabel      matlab.ui.control.Label
        Pause                    matlab.ui.control.NumericEditField
        LiveGraphPanel           matlab.ui.container.Panel
        UIIF                     matlab.ui.control.UIAxes
        UIPhase                  matlab.ui.control.UIAxes
        UIDM                     matlab.ui.control.UIAxes
        ActuatorTitle            matlab.ui.control.Label
        FWHMLabel                matlab.ui.control.Label
        FWHM                     matlab.ui.control.Label
        PositionLabel            matlab.ui.control.Label
        Position                 matlab.ui.control.Label
        AmplitudeLabel           matlab.ui.control.Label
        Amp                      matlab.ui.control.Label
        UIIFPhase                matlab.ui.control.UIAxes
        Panel_2                  matlab.ui.container.Panel
        UIAmplitude              matlab.ui.control.UIAxes
        UIFWHM                   matlab.ui.control.UIAxes
        UIFSign                  matlab.ui.control.UIAxes
        UIPos                    matlab.ui.control.UIAxes
        Play                     matlab.ui.control.Button
        Stop                     matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
    end


    properties (Access = public)
        bench; % Description
        IFMData; % Description
        lastPhaseCounter = 0; % Description
        lastDmCounter = 0;
        qc;
        listeners = {};
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            app.NPushPull.Value = app.bench.config.ifmNpushPull;
            app.Amplitude.Value = app.bench.config.ifmAmplitude;
            app.Nloop.Value =  app.bench.config.ifmNloop;
            app.Pause.Value = app.bench.config.ifmPause;
            % array containing qcs amplitude, fwhm, sign, x, y 
            app.qc = zeros(app.bench.nActuator, 5);
                    
        end
        
        function liveRefresh(app, IFData)
            %IFData.plotScreenPhase(app.UIPhase);
                        
            IFData.plotProfileModel(app.UIIF);
            IFData.plot(app.UIIFPhase);
            act = IFData.getKey(naomi.KEYS.ACTNUM);
            
            profile= IFData.profileFit;
            app.Amp.Text = sprintf( '%4.2f', profile.amplitude);
            app.Position.Text = sprintf( '%5.2f %5.2f', profile.xCenter, profile.yCenter);
            app.FWHM.Text = sprintf('%5.2f', profile.hwhm*2);
            app.qc(act,1) = abs(profile.amplitude);
            app.qc(act,2) = profile.hwhm*2;
            app.qc(act,3) = sign(profile.amplitude);
            app.qc(act,4) =  profile.xCenter;
            app.qc(act,5) =  profile.yCenter;
            
            nActuator = app.bench.nActuator;
            nSubAperture = app.bench.nSubAperture;
            
            plot(app.UIAmplitude, app.qc(1:act,1), 'o-');
            xlim(app.UIAmplitude, [1,nActuator]);
            plot(app.UIFWHM, app.qc(1:act,2) ,'o-');
            xlim(app.UIFWHM,[1,nActuator]);
            plot(app.UIFSign, app.qc(1:act,3) ,'o-');
            xlim(app.UIFSign,[1,nActuator]);
            
            plot(app.UIPos, app.qc(1:act,4), app.qc(1:act,5) ,'o');
            xlim(app.UIPos, [1,nSubAperture]);
            ylim(app.UIPos, [1,nSubAperture]);
            set(app.UIPos,'ydir','reverse');
            %grid(app.UIPos);  set(ax,'xminorgrid','on','yminorgrid','on');
        end
    
        function phaseRefresh(app, varargin)
            if app.lastDmCounter<app.bench.dmCounter
                naomi.plot.dmCommand(app.bench, app.UIDM);
                app.lastDmCounter = app.bench.dmCounter;
            end
            if app.lastPhaseCounter<app.bench.phaseCounter
                naomi.plot.phase(app.bench, app.UIPhase);
                app.lastPhaseCounter = app.bench.phaseCounter;
            end
        end


        function setListeners(app)
            app.clearListeners;
            app.listeners = {listener(app.bench, 'phaseCounter', 'PostSet', @app.phaseRefresh)};
        end
        
        function clearListeners(app)
            for i=1:length(app.listeners)
                delete(app.listeners{i});
            end
            app.listeners = {}; 
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            app.bench = bench;
            app.MeasurementMode.Items = app.bench.config.ifModeChoices;
            app.MeasurementMode.Value = app.bench.config.ifModeChoices{1};
            app.setListeners; 
            app.refresh();
        end

        % Value changed function: MeasurementMode
        function MeasurementModeValueChanged(app, event)
            value = app.MeasurementMode.Value;
            app.bench.config.ifMode = value;
            app.refresh();
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            app.setListeners;
            app.IFMData = naomi.measure.IFM(app.bench, 'callback', @app.liveRefresh);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.bench.killProcess('IFM');
            app.clearListeners;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.bench.killProcess('IFM');
            app.clearListeners;
            pause(0.5);
            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'IFM Measurement';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConfigPanel = uipanel(app.UIFigure);
            app.ConfigPanel.Title = 'Config';
            app.sLabel = uilabel(app.ConfigPanel);
            app.sLabel.Text = 's';
            app.MeasurementModeLabel = uilabel(app.ConfigPanel);
            app.MeasurementModeLabel.Text = 'Measurement Mode';
            app.MeasurementMode = uidropdown(app.ConfigPanel);
            app.MeasurementMode.ValueChangedFcn = createCallbackFcn(app, @MeasurementModeValueChanged, true);
            app.NPushPullEditFieldLabel = uilabel(app.ConfigPanel);
            app.NPushPullEditFieldLabel.Text = 'N Push - Pull';
            app.NPushPull = uieditfield(app.ConfigPanel, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.ConfigPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.ConfigPanel, 'numeric');
            app.NloopEditFieldLabel = uilabel(app.ConfigPanel);
            app.NloopEditFieldLabel.Text = 'N loop';
            app.Nloop = uieditfield(app.ConfigPanel, 'numeric');
            app.PauseEditFieldLabel = uilabel(app.ConfigPanel);
            app.PauseEditFieldLabel.Text = 'Pause';
            app.Pause = uieditfield(app.ConfigPanel, 'numeric');
            app.LiveGraphPanel = uipanel(app.UIFigure);
            app.LiveGraphPanel.Title = 'Live Graph';
            app.UIIF = uiaxes(app.LiveGraphPanel);
            app.UIPhase = uiaxes(app.LiveGraphPanel);
            app.UIDM = uiaxes(app.LiveGraphPanel);
            app.ActuatorTitle = uilabel(app.LiveGraphPanel);
            app.ActuatorTitle.Text = 'Actuator';
            app.FWHMLabel = uilabel(app.LiveGraphPanel);
            app.FWHMLabel.Text = 'FWHM';
            app.FWHM = uilabel(app.LiveGraphPanel);
            app.FWHM.Text = '0.0';
            app.PositionLabel = uilabel(app.LiveGraphPanel);
            app.PositionLabel.Text = 'Position';
            app.Position = uilabel(app.LiveGraphPanel);
            app.Position.Text = '0.0 0.0 ';
            app.AmplitudeLabel = uilabel(app.LiveGraphPanel);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.Amp = uilabel(app.LiveGraphPanel);
            app.Amp.Text = '0.0';
            app.UIIFPhase = uiaxes(app.LiveGraphPanel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = 'Panel';
            app.UIAmplitude = uiaxes(app.Panel_2);
            app.UIFWHM = uiaxes(app.Panel_2);
            app.UIFSign = uiaxes(app.Panel_2);
            app.UIPos = uiaxes(app.Panel_2);
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Text = 'Play';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'Stop';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ifmMeasurementGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end