classdef mainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LivePanel                  matlab.ui.container.Panel
        UIPhase                    matlab.ui.control.UIAxes
        UIDM                       matlab.ui.control.UIAxes
        PhaseInfo                  matlab.ui.control.Label
        ClipLampLabel              matlab.ui.control.Label
        DMClipStatus               matlab.ui.control.Lamp
        MeasurePhase               matlab.ui.control.Button
        ResetDM                    matlab.ui.control.Button
        MaskDropDownLabel          matlab.ui.control.Label
        Mask                       matlab.ui.control.DropDown
        ViewButton                 matlab.ui.control.Button
        Refresh                    matlab.ui.control.Button
        FlatPanel                  matlab.ui.container.Panel
        PlayFlat                   matlab.ui.control.Button
        LoopDropDownLabel          matlab.ui.control.Label
        FlatMode                   matlab.ui.control.DropDown
        NiterEditFieldLabel        matlab.ui.control.Label
        FlatNiter                  matlab.ui.control.NumericEditField
        NZernikeEditFieldLabel     matlab.ui.control.Label
        FlatNZernike               matlab.ui.control.NumericEditField
        GainEditField_2Label       matlab.ui.control.Label
        FlatGain                   matlab.ui.control.NumericEditField
        PhaseFlatInfo              matlab.ui.control.Label
        ViewPhaseFlat              matlab.ui.control.Button
        ZernikePanel               matlab.ui.container.Panel
        LoopDropDownLabel_2        matlab.ui.control.Label
        ZernikeMode                matlab.ui.control.DropDown
        ZernikeEditFieldLabel      matlab.ui.control.Label
        Zernike                    matlab.ui.control.NumericEditField
        NiterEditFieldLabel_2      matlab.ui.control.Label
        ZernikeNiter               matlab.ui.control.NumericEditField
        NZernikeEditFieldLabel_2   matlab.ui.control.Label
        ZernikeNZernike            matlab.ui.control.NumericEditField
        PlayZernike                matlab.ui.control.Button
        UIZernikeInfo              matlab.ui.control.UIAxes
        UIZernikePhase             matlab.ui.control.UIAxes
        AmplitudeEditFieldLabel    matlab.ui.control.Label
        ZernikeAmplitude           matlab.ui.control.NumericEditField
        GainEditFieldLabel         matlab.ui.control.Label
        ZernikeGain                matlab.ui.control.NumericEditField
        PreviousZernike            matlab.ui.control.Button
        NextZernike                matlab.ui.control.Button
        PhaseZernikeInfo           matlab.ui.control.Label
        mrmsLabel                  matlab.ui.control.Label
        ModePushPull               matlab.ui.control.CheckBox
        ActuatorPanel              matlab.ui.container.Panel
        ActNumberEditFieldLabel    matlab.ui.control.Label
        ActuatorNumber             matlab.ui.control.NumericEditField
        PreviousActuator           matlab.ui.control.Button
        NextActuator               matlab.ui.control.Button
        AmplitudeEditField_2Label  matlab.ui.control.Label
        ActuatorAmplitude          matlab.ui.control.NumericEditField
        PlayActuator               matlab.ui.control.Button
        ActuatorPushPull           matlab.ui.control.CheckBox
        CloseButton                matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        MatrixReferenceTab         matlab.ui.container.Tab
        PhaseReferencePanel        matlab.ui.container.Panel
        PhaseReferenceInfo         matlab.ui.control.Label
        MeasurePhaseReference      matlab.ui.control.Button
        LoadPhaseReference         matlab.ui.control.Button
        PhaseRefToggle             matlab.ui.control.CheckBox
        RemoteTipTiltToggle        matlab.ui.control.CheckBox
        ViewPhaseReference         matlab.ui.control.Button
        RemovePhaseReference       matlab.ui.control.Button
        SavePhaseReference         matlab.ui.control.Button
        IFMPanel                   matlab.ui.container.Panel
        IFMInfo                    matlab.ui.control.Label
        MeasureIFM                 matlab.ui.control.Button
        LoadIFM                    matlab.ui.control.Button
        ViewIFM                    matlab.ui.control.Button
        RemoveIFM                  matlab.ui.control.Button
        SaveIFM                    matlab.ui.control.Button
        ZTCPanel                   matlab.ui.container.Panel
        MakeZtC                    matlab.ui.control.Button
        ZtCInfo                    matlab.ui.control.Label
        LoadZtC                    matlab.ui.control.Button
        ViewZtC                    matlab.ui.control.Button
        ModeLabel                  matlab.ui.control.Label
        ZtCMode                    matlab.ui.control.DropDown
        RemoveZtC                  matlab.ui.control.Button
        SaveZtC                    matlab.ui.control.Button
        ZtCModeInfo                matlab.ui.control.Label
        DMBiasPanel                matlab.ui.container.Panel
        BiasInfo                   matlab.ui.control.Label
        LoadBias                   matlab.ui.control.Button
        ViewBias                   matlab.ui.control.Button
        MeasureBias                matlab.ui.control.Button
        RemoveButton               matlab.ui.control.Button
        SaveBias                   matlab.ui.control.Button
        ZtPPanel                   matlab.ui.container.Panel
        ZtPInfo                    matlab.ui.control.Label
        MeasureZtP                 matlab.ui.control.Button
        LoadZtP                    matlab.ui.control.Button
        ViewZtP                    matlab.ui.control.Button
        SaveZtP                    matlab.ui.control.Button
        RemoveZtP                  matlab.ui.control.Button
        PixelScaleDMalignmentTab   matlab.ui.container.Tab
        PixelScalePanel            matlab.ui.container.Panel
        PixelScaleInfo             matlab.ui.control.Label
        MeasureButton              matlab.ui.control.Button
        DMCenterPanel              matlab.ui.container.Panel
        DmCenterInfo               matlab.ui.control.Label
        MeasureDmCenter            matlab.ui.control.Button
        DMAnglePanel               matlab.ui.container.Panel
        DmAngleInfo                matlab.ui.control.Label
        MeasureDmAngle             matlab.ui.control.Button
    end


    properties (Access = public)
        bench; % Description
        currentZernike = 2;
        phaseFlatData; % the last measured flat 
        phaseZernikeData; % the last measured zernike mode 
        currentActuatorNumber = 1;
        
        
        dmDependantWidgets; % Description
        wfsDependantWidgets; % Description
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            if app.bench.has('wfs')
                
                
                % plot the phase sctreen 
                naomi.plot.phase(app.bench, app.UIPhase); title(app.UIPhase, '');
                
                % write phase screen information (rms, ptv)
                app.PhaseInfo.Text = sprintf('rms=%.3fum ptv=%.3fum',...
                                    naomi.compute.nanstd(app.bench.lastPhaseArray(:)),...
                                    max(app.bench.lastPhaseArray(:)) - min(app.bench.lastPhaseArray(:)));
                
                % turn on relevant measure button 
                app.MeasurePhaseReference.Enable = 'on';
                app.MeasureIFM.Enable = 'on';
                app.MeasureZtP.Enable = 'on';
                app.MeasurePhase.Enable = 'on';
                app.PlayFlat.Enable = 'on';
                app.PlayZernike.Enable = 'on';
                
            else
                text(app.UIPhase, 0.5, 0.5, 'No Wave Front', 'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle',...
                    'FontSize',18);
                app.PhaseInfo.Text = '';
                % turn off relevant measure button 
                app.MeasurePhaseReference.Enable = 'off';
                app.MeasureIFM.Enable = 'off';
                app.MeasureZtP.Enable = 'off';
                app.MeasurePhase.Enable = 'off';
                app.PlayFlat.Enable = 'off';
                app.PlayZernike.Enable = 'off';
                app.PlayActuator.Enable = 'off';
            end
            maskName = app.bench.maskName;
            switch maskName
                case {naomi.KEYS.UNKNOWN, naomi.KEYS.CUSTOM}
                    app.Mask.Value = '-';
                otherwise
                    app.Mask.Value = maskName;
            end


            if app.bench.has('dm')
                % plot the dm status
                cmdVector = app.bench.cmdVector + app.bench.biasVector;                   
                naomi.plot.dmCommand(app.bench, app.UIDM);
                app.ResetDM.Enable = 'on';
                % toggle clipping light if DM is saturated 
                if all(abs(cmdVector)<1)
                    app.DMClipStatus.Color = [0,1,0];
                else
                    app.DMClipStatus.Color = [1,0,0];
                end
            else
                text(app.UIDM, 0.5, 0.5, 'No DM', 'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle',...
                    'FontSize',18);
                app.DMClipStatus.Color = [1,1,1];       
                
                % turn off relevant measure button              
                app.MeasureIFM.Enable = 'off';
                app.MeasureZtP.Enable = 'off';
                app.PlayFlat.Enable = 'off';
                app.PlayZernike.Enable = 'off';
                app.ResetDM.Enable = 'off';
                app.PlayActuator.Enable = 'off';
            end
            
            if isempty(app.bench.phaseReferenceData)
                app.ViewPhaseReference.Enable = 'off';
                app.PhaseRefToggle.Value = 0;
                app.PhaseRefToggle.Enable = 'off';
                app.RemovePhaseReference.Enable = 'off';
                app.SavePhaseReference.Enable = 'on';
                app.PhaseReferenceInfo.Text = ''; 
            else 
                app.ViewPhaseReference.Enable = 'on';
                app.PhaseRefToggle.Enable = 'on';
                app.RemovePhaseReference.Enable = 'on';
                app.PhaseRefToggle.Value = app.bench.config.substractReference;
                app.PhaseReferenceInfo.Text = app.bench.phaseReferenceData.shortInfo;
                if isempty(app.bench.phaseReferenceData.file)
                    app.SavePhaseReference.Enable = 'on';
                else
                    app.SavePhaseReference.Enable = 'off';
                end
            end
            app.RemoteTipTiltToggle.Value = app.bench.config.filterTipTilt;
            
            
            if isempty(app.bench.IFMData)
                app.ViewIFM.Enable = 'off';
                app.MakeZtC.Enable = 'off';
                app.RemoveIFM.Enable = 'off';
                app.SaveIFM.Enable = 'off';
                app.IFMInfo.Text = 'No IFM';
            else 
                app.ViewIFM.Enable = 'on';
                app.MakeZtC.Enable = 'on';
                app.RemoveIFM.Enable = 'on';
                app.IFMInfo.Text = app.bench.IFMData.shortInfo;
                if isempty(app.bench.IFMData.file)
                    app.SaveBias.Enable = 'on';
                else
                    app.SaveBias.Enable = 'off';
                end
            end
            
            if isempty(app.bench.dmBiasData)
                app.ViewBias.Enable = 'off';
                app.SaveBias.Enable = 'off';
                app.BiasInfo.Text = 'No Bias';
            else
                app.ViewBias.Enable = 'on';
                app.BiasInfo.Text = app.bench.dmBiasData.shortInfo;
                if isempty(app.bench.dmBiasData.file)
                    app.SaveBias.Enable = 'on';
                else
                    app.SaveBias.Enable = 'off';
                end
            end
               
            if isempty(app.bench.ZtCData)
                app.ViewZtC.Enable = 'off';
                app.SaveZtC.Enable = 'off';
                app.RemoveZtC.Enable = 'off';
                app.ZtCInfo.Text = 'No ZtC';
            else
                app.ViewZtC.Enable = 'on';
                app.RemoveZtC.Enable = 'on';
                app.ZtCInfo.Text = app.bench.ZtCData.shortInfo;
                if isempty(app.bench.ZtCData.file)
                    app.SaveZtC.Enable = 'on';
                else
                    app.SaveZtC.Enable = 'off';
                end
            end
            
            if isempty(app.bench.ZtPData)
                app.ViewZtP.Enable = 'off';
                app.SaveZtP.Enable = 'off';
                app.RemoveZtP.Enable = 'off';
                app.ZtPInfo.Text = 'No ZtP';
            else
                app.ViewZtP.Enable = 'on';
                app.RemoveZtP.Enable = 'on';
                app.ZtPInfo.Text = app.bench.ZtPData.shortInfo;
                if isempty(app.bench.ZtPData.file)
                    app.SaveZtP.Enable = 'on';
                else
                    app.SaveZtP.Enable = 'off';
                end
            end
            
            
            %app.ZtCMode.Value = app.bench.config.ztcMode;
            %app.ZtCNeigenValue.Value = app.bench.config.ztcNeigenValue;
            ztcMode = app.bench.config.ztcMode;
            [mask, maskName,  nEigenValue, nZernike, zeroMean, ztcOrientation] = app.bench.ztcParameters( ztcMode, 'mm');
                
            app.ZtCModeInfo.Text = sprintf('Mask: %.2fmm - %.2fmm, N Eig .%.0f,  N Zern %.0f, zeroMean %.0f orient. %s',mask{1}, mask{2}, nEigenValue, nZernike, zeroMean, ztcOrientation);
            %mask = app.bench.ztcParameters([], 'm');
            %pupillDiameter = mask{1};
            %app.ZtCPupillDiameter.Value = pupillDiameter * 1000;
            
            
            % Enable or disable the Flat loop parameters 
            app.FlatMode.Value = app.bench.config.flatLoopMode;
            switch app.bench.config.flatLoopMode
                case 'open'
                    app.FlatNiter.Enable = 'off';
                    app.FlatNZernike.Enable = 'off';
                    app.FlatGain.Enable = 'off';
                case 'closed'
                    app.FlatNiter.Enable = 'on';
                    app.FlatNZernike.Enable = 'on';   
                    app.FlatGain.Enable = 'on';
            end
            app.FlatNiter.Value = app.bench.config.flatCloseNstep;
            app.FlatNZernike.Value = app.bench.config.flatCloseNzernike;
            app.FlatGain.Value = app.bench.config.flatCloseGain;
            
            if isempty(app.phaseFlatData)
                app.PhaseFlatInfo.Text = '';
                app.ViewPhaseFlat.Enable = 'off';
            else
                app.PhaseFlatInfo.Text = app.phaseFlatData.shortInfo;
                app.ViewPhaseFlat.Enable = 'on';
            end
            
            % Enable or disable the Zernike loop parameters 
            app.ZernikeMode.Value = app.bench.config.zernikeLoopMode;
            switch app.bench.config.zernikeLoopMode
                case 'open'
                    app.ZernikeNiter.Enable = 'off';
                    app.ZernikeNZernike.Enable = 'off';
                    app.ZernikeGain.Enable = 'off';
                    
                case 'closed'
                    app.ZernikeNiter.Enable = 'on';
                    app.ZernikeNZernike.Enable = 'on';    
                    app.ZernikeGain.Enable = 'on';

            end
            app.Zernike.Value = app.currentZernike;
            app.ZernikeNiter.Value = app.bench.config.zernikeCloseNstep;
            app.ZernikeNZernike.Value = app.bench.config.zernikeCloseNzernike;
            app.ZernikeGain.Value = app.bench.config.zernikeCloseGain;
            app.ActuatorPushPull.Value = app.bench.config.zernikeNpushPull;
            
            %% fill the zernike information and representation 
            zernikeData = naomi.data.Zernike(app.currentZernike, {{naomi.KEYS.ZTCORIENT, app.bench.config.ztcOrientation,naomi.KEYS.ZTCORIENTc}});
            zernikeData.plotInfo(app.UIZernikeInfo);
            zernikeData.plotPhase(app.UIZernikePhase);
            
            if isempty(app.phaseZernikeData)
                app.PhaseZernikeInfo.Text = '';
                %app.ViewPhaseZernike.Enable = 'off';
            else
                app.PhaseZernikeInfo.Text = app.phaseZernikeData.shortInfo;
                %app.ViewPhaseZernike.Enable = 'on';
            end
            
            
            app.ActuatorNumber.Value = app.currentActuatorNumber;
            app.ActuatorAmplitude.Value = app.bench.config.ifAmplitude;


            % put some wrning color if the loaded file are not of the right rm 
            dmId = app.bench.config.dmId;
            if ~isempty(app.bench.IFMData)
                if strcmp(app.bench.IFMData.dmId, dmId)
                    app.IFMInfo.FontColor = [0,0,0];
                else
                    app.IFMInfo.FontColor = [1,0,0];
                end
            end
            if ~isempty(app.bench.ZtCData)
                if strcmp(app.bench.ZtCData.dmId, dmId)
                    app.ZtCInfo.FontColor = [0,0,0];
                else
                    app.ZtCInfo.FontColor = [1,0,0];
                end
            end
            if ~isempty(app.bench.ZtPData)
                if strcmp(app.bench.ZtPData.dmId, dmId)
                    app.ZtPInfo.FontColor = [0,0,0];
                else
                    app.ZtPInfo.FontColor = [1,0,0];
                end
            end
            
            if ~isempty(app.bench.dmBiasData)
                if strcmp(app.bench.dmBiasData.dmId, dmId)
                    app.BiasInfo.FontColor = [0,0,0];
                else
                    app.BiasInfo.FontColor = [1,0,0];
                end
            end
            
            if app.bench.isScaled
                app.PixelScaleInfo.Text = sprintf('X %.3fmm/pixel  Y %.3fmm/pixel',app.bench.xPixelScale*1000, app.bench.yPixelScale*1000);
            else
                app.PixelScaleInfo.Text = 'not measured';
            end
            
            if app.bench.isCentered
                if app.bench.isScaled
                    app.DmCenterInfo.Text = sprintf('X: %.2fpixel  Y: %.2fpixel -> X: %.2fmm  Y: %.2fmm', app.bench.xCenter, app.bench.yCenter, app.bench.pixel2meter(app.bench.xCenter)*1000, app.bench.pixel2meter(app.bench.yCenter)*1000);
                else
                    app.DmCenterInfo.Text = sprintf('X: %.2fpixel  Y: %.2fpixel', app.bench.xCenter, app.bench.yCenter);
                end
            else
                app.DmCenterInfo.Text = 'not measured';
            end
            
            
            if app.bench.isDmAngleMeasured
                app.DmAngleInfo.Text = sprintf(' %.5f rad %.2f degree',app.bench.dmAngle, app.bench.dmAngle*180/pi);
            else
                app.DmAngleInfo.Text = 'not measured';
            end
%             if isempty(app.bench.SignData)
%                 app.ViewSign.Enable = 'off';
%             else
%                 app.ViewSign.Enable = 'on';
%             end


        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            app.bench = bench;
            
            
            app.ZtCMode.Items = app.bench.config.ztcModeChoices;
            app.ZtCMode.Value = app.ZtCMode.Items{1};
            maskes = ['-' app.bench.config.maskChoices]; 
            app.Mask.Items = maskes;
            app.Mask.Value = '-';
            if app.bench.nActuator
                app.ActuatorNumber.Limits = [1 app.bench.nActuator];
            end
            app.dmDependantWidgets = {app.ResetDM, app.MeasureIFM, app.MeasureZtP,...,
                app.PlayFlat, app.PlayActuator, app.PlayZernike};
            app.wfsDependantWidgets = {app.MeasureIFM, app.MeasureZtP,...,
                app.PlayFlat, app.PlayActuator, app.PlayZernike, app.MeasurePhase, app.MeasurePhaseReference};
            app.refresh();
        end

        % Button pushed function: ViewIFM
        function ViewIFMButtonPushed(app, event)
            naomi.gui.ifmExplorerGui(app.bench.IFMData);
        end

        % Button pushed function: LoadIFM
        function LoadIFMButtonPushed(app, event)
            naomi.config.IFM(app.bench);
            app.refresh();
        end

        % Button pushed function: MakeZtC
        function MakeZtCButtonPushed(app, event)
            ZtCData = naomi.make.ZtC(app.bench);
            naomi.config.ZtC(app.bench, ZtCData);
            app.refresh();
        end

        % Value changed function: ZtCMode
        function ZtCModeValueChanged(app, event)
            value = app.ZtCMode.Value;
            app.bench.config.ztcMode = value;
            app.refresh();
        end

        % Callback function
        function ZtCPupillDiameterValueChanged(app, event)
            value = app.ZtCPupillDiameter.Value;
            app.bench.config.ztcPupillDiamter = value/1000;
        end

        % Callback function
        function ZtCNeigenValueValueChanged(app, event)
            value = app.ZtCNeigenValue.Value;
            app.bench.config.ztcNeigenValue = value;
        end

        % Button pushed function: LoadZtC
        function LoadZtCButtonPushed(app, event)
            naomi.config.ZtC(app.bench);
            app.refresh();
        end

        % Button pushed function: Refresh
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Button pushed function: ViewZtC
        function ViewZtCButtonPushed(app, event)
            naomi.gui.ztcExplorerGui(app.bench.ZtCData);
        end

        % Button pushed function: LoadZtP
        function LoadZtPButtonPushed(app, event)
            naomi.config.ZtP(app.bench);
            app.refresh();
        end

        % Button pushed function: ViewZtP
        function ViewZtPButtonPushed(app, event)
            naomi.gui.ztpExplorerGui(app.bench.ZtPData); 
        end

        % Button pushed function: MeasurePhase
        function MeasurePhasePushed(app, event)
            naomi.measure.phase(app.bench);
            app.refresh();
        end

        % Button pushed function: PlayFlat
        function PlayFlatButtonPushed(app, event)
            loopMode = app.FlatMode.Value;
            switch app.bench.config.flatLoopMode
                case 'open'
                    [~,app.phaseFlatData] = naomi.measure.openFlat(app.bench);
                case 'closed'
                    [~,app.phaseFlatData] = naomi.measure.closeFlat(app.bench);
            end
            app.refresh();
        end

        % Value changed function: FlatNiter
        function FlatNiterValueChanged(app, event)
            value = app.FlatNiter.Value;
            app.bench.config.flatCloseNstep = value;
            
        end

        % Value changed function: FlatNZernike
        function FlatNZernikeValueChanged(app, event)
            value = app.FlatNZernike.Value;
            app.bench.config.flatCloseNzernike  = value;
        end

        % Value changed function: FlatMode
        function FlatModeValueChanged(app, event)
            value = app.FlatMode.Value;
            app.bench.config.flatLoopMode = value;
            app.refresh();
        end

        % Value changed function: ZernikeMode
        function ZernikeModeValueChanged(app, event)
            value = app.ZernikeMode.Value;
            app.bench.config.zernikeLoopMode = value;
            app.refresh();
        end

        % Value changed function: ZernikeNiter
        function ZernikeNiterValueChanged(app, event)
            value = app.ZernikeNiter.Value;
            app.bench.config.zernikeCloseNstep = value;
        end

        % Value changed function: ZernikeNZernike
        function ZernikeNZernikeValueChanged(app, event)
            value = app.ZernikeNZernike.Value;
            app.bench.config.zernikeCloseNzernike = value;
        end

        % Value changed function: Zernike
        function ZernikeValueChanged(app, event)
            value = app.Zernike.Value;
            app.currentZernike = value;
            app.refresh();
        end

        % Value changed function: ZernikeGain
        function ZernikeGainValueChanged(app, event)
            value = app.ZernikeGain.Value;
            app.bench.config.zernikeCloseGain = value;
        end

        % Button pushed function: PlayZernike
        function PlayZernikeButtonPushed(app, event)
            switch app.bench.config.zernikeLoopMode
                case 'open'  
                    if app.ModePushPull.Value
                         nPushPull = 1;
                    else
                        nPushPull = 0;
                    end
                    [phaseArray,app.phaseZernikeData] = naomi.measure.openZernike(app.bench, app.currentZernike, 'amplitude', app.ZernikeAmplitude.Value,  'nPushPull',nPushPull);
                    app.bench.lastPhaseArray = phaseArray;
                case 'closed'
                    [~,~,app.phaseZernikeData] = naomi.measure.closeZernike(app.bench, app.currentZernike, 'amplitude', app.ZernikeAmplitude.Value);
                    naomi.measure.phase(app.bench);
            end
            
            app.refresh();
        end

        % Value changed function: FlatGain
        function FlatGainValueChanged(app, event)
            value = app.FlatGain.Value;
            app.bench.config.flatCloseGain = value;
        end

        % Button pushed function: PreviousZernike
        function PreviousZernikePushed(app, event)
            if app.currentZernike>1
                app.currentZernike = app.currentZernike-1;
                app.refresh();
            end
        end

        % Button pushed function: NextZernike
        function NextZernikeButtonPushed(app, event)
            app.currentZernike = app.currentZernike +1;
            app.refresh();
        end

        % Value changed function: PhaseRefToggle
        function PhaseRefToggleValueChanged(app, event)
            value = app.PhaseRefToggle.Value;
            app.bench.config.substractReference = value;
        end

        % Value changed function: RemoteTipTiltToggle
        function RemoteTipTiltToggleValueChanged(app, event)
            value = app.RemoteTipTiltToggle.Value;
            app.bench.config.filterTipTilt = value;
        end

        % Button pushed function: LoadPhaseReference
        function LoadPhaseReferenceButtonPushed(app, event)
            naomi.config.phaseReference(app.bench);
            app.refresh();
        end

        % Button pushed function: ViewPhaseReference
        function ViewPhaseReferenceButtonPushed(app, event)
            naomi.gui.phaseExplorerGui(app.bench.phaseReferenceData);
        end

        % Button pushed function: LoadBias
        function LoadBiasButtonPushed(app, event)
            naomi.config.dmBias(app.bench);
            app.refresh();
        end

        % Button pushed function: ViewBias
        function ViewBiasButtonPushed(app, event)
            naomi.gui.dmBiasExplorerGui(app.bench.dmBiasData);
        end

        % Button pushed function: ResetDM
        function ResetDMButtonPushed(app, event)
            naomi.action.resetDm(app.bench);
            if app.bench.has('wfs')
                naomi.measure.phase(app.bench);
            end
            app.refresh();
        end

        % Value changed function: ActuatorNumber
        function ActuatorNumberValueChanged(app, event)
            value = app.ActuatorNumber.Value;
            app.currentActuatorNumber = value;
        end

        % Value changed function: ActuatorAmplitude
        function ActuatorAmplitudeValueChanged(app, event)
            value = app.ActuatorAmplitude.Value;
            app.bench.config.ifAmplitude = value;
        end

        % Button pushed function: PreviousActuator
        function PreviousActuatorButtonPushed(app, event)

            app.currentActuatorNumber = max(1, app.currentActuatorNumber-1);
            app.refresh();
        end

        % Button pushed function: NextActuator
        function NextActuatorButtonPushed(app, event)
            if app.bench.has('dm')
                app.currentActuatorNumber = min(app.bench.nActuator, app.currentActuatorNumber+1);
            else
                app.currentActuatorNumber = app.currentActuatorNumber+1;
            end
            app.refresh();
        end

        % Button pushed function: PlayActuator
        function PlayActuatorButtonPushed(app, event)
            naomi.action.resetDm(app.bench);
            if app.ActuatorPushPull.Value
                app.bench.lastPhaseArray = naomi.measure.IF(app.bench, app.currentActuatorNumber);
            else
                naomi.action.cmdZonal(app.bench, app.currentActuatorNumber, app.bench.config.ifAmplitude);
                naomi.measure.phase(app.bench);
            end
            app.refresh();
        end

        % Callback function
        function MirrorValueChanged(app, event)

        end

        % Value changed function: Mask
        function MaskValueChanged(app, event)
            value = app.Mask.Value;
            if strcmp(value,'-')
                naomi.config.mask(app.bench, []);
            else
                
                naomi.config.pupillMask(app.bench, value);
            end
            naomi.measure.phase(app.bench); % refresh 
            app.refresh();
        end

        % Button pushed function: MeasureIFM
        function MeasureIFMButtonPushed(app, event)
            naomi.gui.ifmMeasurementGui(app.bench);
        end

        % Button pushed function: MeasurePhaseReference
        function MeasurePhaseReferenceButtonPushed(app, event)
            [~,phaseReferenceData] = naomi.measure.phaseReference(app.bench);
            naomi.config.phaseReference(app.bench, phaseReferenceData);
            app.refresh();
        end

        % Button pushed function: MeasureZtP
        function MeasureZtPButtonPushed(app, event)
            ZtPData = naomi.measure.ZtP(app.bench);
            naomi.config.ZtP(app.bench, ZtPData);
            app.refresh();
        end

        % Button pushed function: MeasureBias
        function MeasureBiasButtonPushed(app, event)
            [~, dmBiasData] = naomi.measure.dmBias(app.bench);
            naomi.config.dmBias(app.bench, dmBiasData);
            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            naomi.config.dmBias(app.bench, []);
            app.refresh();
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
             naomi.gui.phaseExplorerGui(naomi.data.Phase(app.bench.lastPhaseArray));
        end

        % Value changed function: ActuatorPushPull
        function ActuatorPushPullValueChanged(app, event)
            value = app.ActuatorPushPull.Value;
            if ~value
                selection = uiconfirm(app.UIFigure,{'Not Doing a push-pull can be harmfull to the DM', 'Make sure to reset the DM soon after the measurement'},'Confirm Push pull removal',...
                        'Icon','warning');
                
                switch selection
                case 'OK'
                  
                otherwise
                   app.ActuatorPushPull.Value = 1;
                end
            end
        end

        % Value changed function: ModePushPull
        function ModePushPullValueChanged(app, event)
            value = app.ModePushPull.Value;
             
            if ~value
                selection = uiconfirm(app.UIFigure,{'Not Doing a push-pull can be harmfull to the DM', 'Make sure to reset the DM soon after the measurement'},'Confirm Push pull removal',...
                        'Icon','warning');
                
                switch selection
                case 'OK'
                  app.bench.config.zernikeNpushPull = 1;
                otherwise
                   app.ActuatorPushPull.Value = 1;
                   app.bench.config.zernikeNpushPull = 0;
                end
            else
                app.bench.config.zernikeNpushPull = 1;
            end
        end

        % Button pushed function: SaveBias
        function SaveBiasButtonPushed(app, event)
            if ~isempty(app.bench.dmBiasData)
                naomi.saveData(app.bench, app.bench.dmBiasData);
            end
        end

        % Button pushed function: RemoveZtP
        function RemoveZtPButtonPushed(app, event)
            naomi.config.ZtP(app.bench, []);
            app.refresh();
        end

        % Button pushed function: SaveZtP
        function SaveZtPButtonPushed(app, event)
            if ~isempty(app.bench.ZtPData)
                naomi.saveData(app.bench, app.bench.ZtPData);
            end
        end

        % Button pushed function: RemoveZtC
        function RemoveZtCButtonPushed(app, event)
            naomi.config.ZtC(app.bench, []);
            app.refresh();
        end

        % Button pushed function: SaveZtC
        function SaveZtCButtonPushed(app, event)
            if ~isempty(app.bench.ZtCData)
                naomi.saveData(app.bench, app.bench.ZtCData);
            end
        end

        % Button pushed function: RemoveIFM
        function RemoveIFMButtonPushed(app, event)
            naomi.config.IFM(app.bench, []);
            app.refresh();
        end

        % Button pushed function: SaveIFM
        function SaveIFMButtonPushed(app, event)
            if ~isempty(app.bench.IFMData)
                naomi.saveData(app.bench, app.bench.IFMData);
            end
        end

        % Button pushed function: RemovePhaseReference
        function RemovePhaseReferenceButtonPushed(app, event)
            naomi.config.phaseReference(app.bench, []);
            app.refresh();
        end

        % Button pushed function: SavePhaseReference
        function SavePhaseReferenceButtonPushed(app, event)
            if ~isempty(app.bench.phaseReferenceData)
                naomi.saveData(app.bench, app.bench.phaseReferenceData);
            end
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            naomi.task.pixelScale(app.bench);
            app.refresh();
        end

        % Button pushed function: MeasureDmCenter
        function MeasureDmCenterButtonPushed(app, event)
            naomi.task.dmCenter(app.bench);
            app.refresh();
        end

        % Button pushed function: MeasureDmAngle
        function MeasureDmAngleButtonPushed(app, event)
            naomi.task.dmAngle(app.bench);
            app.refresh();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Engineering';
            app.LivePanel = uipanel(app.UIFigure);
            app.LivePanel.Title = 'Live';
            app.UIPhase = uiaxes(app.LivePanel);
            app.UIDM = uiaxes(app.LivePanel);
            app.PhaseInfo = uilabel(app.LivePanel);
            app.PhaseInfo.Text = 'PhaseInfo';
            app.ClipLampLabel = uilabel(app.LivePanel);
            app.ClipLampLabel.Text = 'Clip';
            app.DMClipStatus = uilamp(app.LivePanel);
            app.MeasurePhase = uibutton(app.LivePanel, 'push');
            app.MeasurePhase.ButtonPushedFcn = createCallbackFcn(app, @MeasurePhasePushed, true);
            app.MeasurePhase.Text = 'Mesure Phase';
            app.ResetDM = uibutton(app.LivePanel, 'push');
            app.ResetDM.ButtonPushedFcn = createCallbackFcn(app, @ResetDMButtonPushed, true);
            app.ResetDM.Text = 'Reset';
            app.MaskDropDownLabel = uilabel(app.LivePanel);
            app.MaskDropDownLabel.Text = 'Mask';
            app.Mask = uidropdown(app.LivePanel);
            app.Mask.ValueChangedFcn = createCallbackFcn(app, @MaskValueChanged, true);
            app.ViewButton = uibutton(app.LivePanel, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.Refresh = uibutton(app.UIFigure, 'push');
            app.Refresh.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.Refresh.Text = 'Refresh';
            app.FlatPanel = uipanel(app.UIFigure);
            app.FlatPanel.Title = 'Flat';
            app.PlayFlat = uibutton(app.FlatPanel, 'push');
            app.PlayFlat.ButtonPushedFcn = createCallbackFcn(app, @PlayFlatButtonPushed, true);
            app.PlayFlat.Text = 'Play';
            app.LoopDropDownLabel = uilabel(app.FlatPanel);
            app.LoopDropDownLabel.Text = 'Loop';
            app.FlatMode = uidropdown(app.FlatPanel);
            app.FlatMode.Items = {'open', 'closed'};
            app.FlatMode.ValueChangedFcn = createCallbackFcn(app, @FlatModeValueChanged, true);
            app.FlatMode.Value = 'open';
            app.NiterEditFieldLabel = uilabel(app.FlatPanel);
            app.NiterEditFieldLabel.Text = 'N iter';
            app.FlatNiter = uieditfield(app.FlatPanel, 'numeric');
            app.FlatNiter.Limits = [1 50];
            app.FlatNiter.ValueDisplayFormat = '%.0f';
            app.FlatNiter.ValueChangedFcn = createCallbackFcn(app, @FlatNiterValueChanged, true);
            app.FlatNiter.Value = 1;
            app.NZernikeEditFieldLabel = uilabel(app.FlatPanel);
            app.NZernikeEditFieldLabel.Text = 'N Zernike';
            app.FlatNZernike = uieditfield(app.FlatPanel, 'numeric');
            app.FlatNZernike.ValueChangedFcn = createCallbackFcn(app, @FlatNZernikeValueChanged, true);
            app.GainEditField_2Label = uilabel(app.FlatPanel);
            app.GainEditField_2Label.Text = 'Gain';
            app.FlatGain = uieditfield(app.FlatPanel, 'numeric');
            app.FlatGain.ValueChangedFcn = createCallbackFcn(app, @FlatGainValueChanged, true);
            app.PhaseFlatInfo = uilabel(app.FlatPanel);
            app.PhaseFlatInfo.Text = 'Phase Flat Info';
            app.ViewPhaseFlat = uibutton(app.FlatPanel, 'push');
            app.ViewPhaseFlat.Text = 'View';
            app.ZernikePanel = uipanel(app.UIFigure);
            app.ZernikePanel.Title = 'Zernike';
            app.LoopDropDownLabel_2 = uilabel(app.ZernikePanel);
            app.LoopDropDownLabel_2.Text = 'Loop';
            app.ZernikeMode = uidropdown(app.ZernikePanel);
            app.ZernikeMode.Items = {'open', 'closed'};
            app.ZernikeMode.ValueChangedFcn = createCallbackFcn(app, @ZernikeModeValueChanged, true);
            app.ZernikeMode.Value = 'open';
            app.ZernikeEditFieldLabel = uilabel(app.ZernikePanel);
            app.ZernikeEditFieldLabel.Text = 'Zernike';
            app.Zernike = uieditfield(app.ZernikePanel, 'numeric');
            app.Zernike.ValueChangedFcn = createCallbackFcn(app, @ZernikeValueChanged, true);
            app.Zernike.Value = 2;
            app.NiterEditFieldLabel_2 = uilabel(app.ZernikePanel);
            app.NiterEditFieldLabel_2.Text = 'N iter';
            app.ZernikeNiter = uieditfield(app.ZernikePanel, 'numeric');
            app.ZernikeNiter.Limits = [1 50];
            app.ZernikeNiter.ValueDisplayFormat = '%.0f';
            app.ZernikeNiter.ValueChangedFcn = createCallbackFcn(app, @ZernikeNiterValueChanged, true);
            app.ZernikeNiter.Value = 1;
            app.NZernikeEditFieldLabel_2 = uilabel(app.ZernikePanel);
            app.NZernikeEditFieldLabel_2.Text = 'N Zernike';
            app.ZernikeNZernike = uieditfield(app.ZernikePanel, 'numeric');
            app.ZernikeNZernike.ValueChangedFcn = createCallbackFcn(app, @ZernikeNZernikeValueChanged, true);
            app.PlayZernike = uibutton(app.ZernikePanel, 'push');
            app.PlayZernike.ButtonPushedFcn = createCallbackFcn(app, @PlayZernikeButtonPushed, true);
            app.PlayZernike.Text = 'Play';
            app.UIZernikeInfo = uiaxes(app.ZernikePanel);
            app.UIZernikePhase = uiaxes(app.ZernikePanel);
            app.AmplitudeEditFieldLabel = uilabel(app.ZernikePanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.ZernikeAmplitude = uieditfield(app.ZernikePanel, 'numeric');
            app.ZernikeAmplitude.Value = 1;
            app.GainEditFieldLabel = uilabel(app.ZernikePanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.ZernikeGain = uieditfield(app.ZernikePanel, 'numeric');
            app.ZernikeGain.ValueChangedFcn = createCallbackFcn(app, @ZernikeGainValueChanged, true);
            app.PreviousZernike = uibutton(app.ZernikePanel, 'push');
            app.PreviousZernike.ButtonPushedFcn = createCallbackFcn(app, @PreviousZernikePushed, true);
            app.PreviousZernike.Text = '-';
            app.NextZernike = uibutton(app.ZernikePanel, 'push');
            app.NextZernike.ButtonPushedFcn = createCallbackFcn(app, @NextZernikeButtonPushed, true);
            app.NextZernike.Text = '+';
            app.PhaseZernikeInfo = uilabel(app.ZernikePanel);
            app.PhaseZernikeInfo.Text = 'Zernike Flat Info';
            app.mrmsLabel = uilabel(app.ZernikePanel);
            app.mrmsLabel.Text = 'µm rms';
            app.ModePushPull = uicheckbox(app.ZernikePanel);
            app.ModePushPull.ValueChangedFcn = createCallbackFcn(app, @ModePushPullValueChanged, true);
            app.ModePushPull.Text = 'Push - Pull';
            app.ModePushPull.Value = true;
            app.ActuatorPanel = uipanel(app.UIFigure);
            app.ActuatorPanel.Title = 'Actuator';
            app.ActNumberEditFieldLabel = uilabel(app.ActuatorPanel);
            app.ActNumberEditFieldLabel.Text = 'Act Number';
            app.ActuatorNumber = uieditfield(app.ActuatorPanel, 'numeric');
            app.ActuatorNumber.Limits = [1 Inf];
            app.ActuatorNumber.ValueDisplayFormat = '%.0f';
            app.ActuatorNumber.ValueChangedFcn = createCallbackFcn(app, @ActuatorNumberValueChanged, true);
            app.ActuatorNumber.Value = 1;
            app.PreviousActuator = uibutton(app.ActuatorPanel, 'push');
            app.PreviousActuator.ButtonPushedFcn = createCallbackFcn(app, @PreviousActuatorButtonPushed, true);
            app.PreviousActuator.Text = '-';
            app.NextActuator = uibutton(app.ActuatorPanel, 'push');
            app.NextActuator.ButtonPushedFcn = createCallbackFcn(app, @NextActuatorButtonPushed, true);
            app.NextActuator.Text = '+';
            app.AmplitudeEditField_2Label = uilabel(app.ActuatorPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.ActuatorAmplitude = uieditfield(app.ActuatorPanel, 'numeric');
            app.ActuatorAmplitude.ValueChangedFcn = createCallbackFcn(app, @ActuatorAmplitudeValueChanged, true);
            app.PlayActuator = uibutton(app.ActuatorPanel, 'push');
            app.PlayActuator.ButtonPushedFcn = createCallbackFcn(app, @PlayActuatorButtonPushed, true);
            app.PlayActuator.Text = 'Play';
            app.ActuatorPushPull = uicheckbox(app.ActuatorPanel);
            app.ActuatorPushPull.ValueChangedFcn = createCallbackFcn(app, @ActuatorPushPullValueChanged, true);
            app.ActuatorPushPull.Text = 'Push - Pull';
            app.ActuatorPushPull.Value = true;
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MatrixReferenceTab = uitab(app.TabGroup);
            app.MatrixReferenceTab.Title = 'Matrix / Reference';
            app.PhaseReferencePanel = uipanel(app.MatrixReferenceTab);
            app.PhaseReferencePanel.Title = 'Phase Reference';
            app.PhaseReferenceInfo = uilabel(app.PhaseReferencePanel);
            app.PhaseReferenceInfo.Text = 'Phase Reference Info';
            app.MeasurePhaseReference = uibutton(app.PhaseReferencePanel, 'push');
            app.MeasurePhaseReference.ButtonPushedFcn = createCallbackFcn(app, @MeasurePhaseReferenceButtonPushed, true);
            app.MeasurePhaseReference.Text = 'Measure';
            app.LoadPhaseReference = uibutton(app.PhaseReferencePanel, 'push');
            app.LoadPhaseReference.ButtonPushedFcn = createCallbackFcn(app, @LoadPhaseReferenceButtonPushed, true);
            app.LoadPhaseReference.Text = 'Load';
            app.PhaseRefToggle = uicheckbox(app.PhaseReferencePanel);
            app.PhaseRefToggle.ValueChangedFcn = createCallbackFcn(app, @PhaseRefToggleValueChanged, true);
            app.PhaseRefToggle.Text = 'Substract';
            app.RemoteTipTiltToggle = uicheckbox(app.PhaseReferencePanel);
            app.RemoteTipTiltToggle.ValueChangedFcn = createCallbackFcn(app, @RemoteTipTiltToggleValueChanged, true);
            app.RemoteTipTiltToggle.Text = 'Remote Tip/Tilt';
            app.ViewPhaseReference = uibutton(app.PhaseReferencePanel, 'push');
            app.ViewPhaseReference.ButtonPushedFcn = createCallbackFcn(app, @ViewPhaseReferenceButtonPushed, true);
            app.ViewPhaseReference.Text = 'View';
            app.RemovePhaseReference = uibutton(app.PhaseReferencePanel, 'push');
            app.RemovePhaseReference.ButtonPushedFcn = createCallbackFcn(app, @RemovePhaseReferenceButtonPushed, true);
            app.RemovePhaseReference.Text = 'Remove';
            app.SavePhaseReference = uibutton(app.PhaseReferencePanel, 'push');
            app.SavePhaseReference.ButtonPushedFcn = createCallbackFcn(app, @SavePhaseReferenceButtonPushed, true);
            app.SavePhaseReference.Text = 'Save';
            app.IFMPanel = uipanel(app.MatrixReferenceTab);
            app.IFMPanel.Title = 'IFM';
            app.IFMInfo = uilabel(app.IFMPanel);
            app.IFMInfo.Text = 'IFM Info';
            app.MeasureIFM = uibutton(app.IFMPanel, 'push');
            app.MeasureIFM.ButtonPushedFcn = createCallbackFcn(app, @MeasureIFMButtonPushed, true);
            app.MeasureIFM.Text = 'Measure';
            app.LoadIFM = uibutton(app.IFMPanel, 'push');
            app.LoadIFM.ButtonPushedFcn = createCallbackFcn(app, @LoadIFMButtonPushed, true);
            app.LoadIFM.Text = 'Load';
            app.ViewIFM = uibutton(app.IFMPanel, 'push');
            app.ViewIFM.ButtonPushedFcn = createCallbackFcn(app, @ViewIFMButtonPushed, true);
            app.ViewIFM.Text = 'View';
            app.RemoveIFM = uibutton(app.IFMPanel, 'push');
            app.RemoveIFM.ButtonPushedFcn = createCallbackFcn(app, @RemoveIFMButtonPushed, true);
            app.RemoveIFM.Text = 'Remove';
            app.SaveIFM = uibutton(app.IFMPanel, 'push');
            app.SaveIFM.ButtonPushedFcn = createCallbackFcn(app, @SaveIFMButtonPushed, true);
            app.SaveIFM.Text = 'Save';
            app.ZTCPanel = uipanel(app.MatrixReferenceTab);
            app.ZTCPanel.Title = 'ZTC';
            app.MakeZtC = uibutton(app.ZTCPanel, 'push');
            app.MakeZtC.ButtonPushedFcn = createCallbackFcn(app, @MakeZtCButtonPushed, true);
            app.MakeZtC.Text = 'Make From IFM ';
            app.ZtCInfo = uilabel(app.ZTCPanel);
            app.ZtCInfo.Text = 'ZtC Info';
            app.LoadZtC = uibutton(app.ZTCPanel, 'push');
            app.LoadZtC.ButtonPushedFcn = createCallbackFcn(app, @LoadZtCButtonPushed, true);
            app.LoadZtC.Text = 'Load';
            app.ViewZtC = uibutton(app.ZTCPanel, 'push');
            app.ViewZtC.ButtonPushedFcn = createCallbackFcn(app, @ViewZtCButtonPushed, true);
            app.ViewZtC.Text = 'View';
            app.ModeLabel = uilabel(app.ZTCPanel);
            app.ModeLabel.Text = 'Mode';
            app.ZtCMode = uidropdown(app.ZTCPanel);
            app.ZtCMode.ValueChangedFcn = createCallbackFcn(app, @ZtCModeValueChanged, true);
            app.RemoveZtC = uibutton(app.ZTCPanel, 'push');
            app.RemoveZtC.ButtonPushedFcn = createCallbackFcn(app, @RemoveZtCButtonPushed, true);
            app.RemoveZtC.Text = 'Remove';
            app.SaveZtC = uibutton(app.ZTCPanel, 'push');
            app.SaveZtC.ButtonPushedFcn = createCallbackFcn(app, @SaveZtCButtonPushed, true);
            app.SaveZtC.Text = 'Save';
            app.ZtCModeInfo = uilabel(app.ZTCPanel);
            app.DMBiasPanel = uipanel(app.MatrixReferenceTab);
            app.DMBiasPanel.Title = 'DM Bias';
            app.BiasInfo = uilabel(app.DMBiasPanel);
            app.BiasInfo.Text = 'Bias Info';
            app.LoadBias = uibutton(app.DMBiasPanel, 'push');
            app.LoadBias.ButtonPushedFcn = createCallbackFcn(app, @LoadBiasButtonPushed, true);
            app.LoadBias.Text = 'Load';
            app.ViewBias = uibutton(app.DMBiasPanel, 'push');
            app.ViewBias.ButtonPushedFcn = createCallbackFcn(app, @ViewBiasButtonPushed, true);
            app.ViewBias.Text = 'View';
            app.MeasureBias = uibutton(app.DMBiasPanel, 'push');
            app.MeasureBias.ButtonPushedFcn = createCallbackFcn(app, @MeasureBiasButtonPushed, true);
            app.MeasureBias.Text = 'Measure';
            app.RemoveButton = uibutton(app.DMBiasPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SaveBias = uibutton(app.DMBiasPanel, 'push');
            app.SaveBias.ButtonPushedFcn = createCallbackFcn(app, @SaveBiasButtonPushed, true);
            app.SaveBias.Text = 'Save';
            app.ZtPPanel = uipanel(app.MatrixReferenceTab);
            app.ZtPPanel.Title = 'ZtP';
            app.ZtPInfo = uilabel(app.ZtPPanel);
            app.ZtPInfo.Text = 'ZtP Info';
            app.MeasureZtP = uibutton(app.ZtPPanel, 'push');
            app.MeasureZtP.ButtonPushedFcn = createCallbackFcn(app, @MeasureZtPButtonPushed, true);
            app.MeasureZtP.Text = 'Measure';
            app.LoadZtP = uibutton(app.ZtPPanel, 'push');
            app.LoadZtP.ButtonPushedFcn = createCallbackFcn(app, @LoadZtPButtonPushed, true);
            app.LoadZtP.Text = 'Load';
            app.ViewZtP = uibutton(app.ZtPPanel, 'push');
            app.ViewZtP.ButtonPushedFcn = createCallbackFcn(app, @ViewZtPButtonPushed, true);
            app.ViewZtP.Text = 'View';
            app.SaveZtP = uibutton(app.ZtPPanel, 'push');
            app.SaveZtP.ButtonPushedFcn = createCallbackFcn(app, @SaveZtPButtonPushed, true);
            app.SaveZtP.Text = 'Save';
            app.RemoveZtP = uibutton(app.ZtPPanel, 'push');
            app.RemoveZtP.ButtonPushedFcn = createCallbackFcn(app, @RemoveZtPButtonPushed, true);
            app.RemoveZtP.Text = 'Remove';
            app.PixelScaleDMalignmentTab = uitab(app.TabGroup);
            app.PixelScaleDMalignmentTab.Title = 'Pixel Scale / DM alignment';
            app.PixelScalePanel = uipanel(app.PixelScaleDMalignmentTab);
            app.PixelScalePanel.Title = 'Pixel Scale';
            app.PixelScaleInfo = uilabel(app.PixelScalePanel);
            app.PixelScaleInfo.Text = 'Pixel Scale';
            app.MeasureButton = uibutton(app.PixelScalePanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Text = 'Measure';
            app.DMCenterPanel = uipanel(app.PixelScaleDMalignmentTab);
            app.DMCenterPanel.Title = 'DM Center';
            app.DmCenterInfo = uilabel(app.DMCenterPanel);
            app.DmCenterInfo.Text = 'Dm Center';
            app.MeasureDmCenter = uibutton(app.DMCenterPanel, 'push');
            app.MeasureDmCenter.ButtonPushedFcn = createCallbackFcn(app, @MeasureDmCenterButtonPushed, true);
            app.MeasureDmCenter.Text = 'Measure';
            app.DMAnglePanel = uipanel(app.PixelScaleDMalignmentTab);
            app.DMAnglePanel.Title = 'DM Angle';
            app.DmAngleInfo = uilabel(app.DMAnglePanel);
            app.DmAngleInfo.Text = 'Dm Angle';
            app.MeasureDmAngle = uibutton(app.DMAnglePanel, 'push');
            app.MeasureDmAngle.ButtonPushedFcn = createCallbackFcn(app, @MeasureDmAngleButtonPushed, true);
            app.MeasureDmAngle.Text = 'Measure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end