classdef startupGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PanelMenu                   matlab.ui.container.Menu
        AlignmentMenu               matlab.ui.container.Menu
        CalibrationMenu             matlab.ui.container.Menu
        EnvironmentMenu             matlab.ui.container.Menu
        EngineeringMenu             matlab.ui.container.Menu
        MirrorDropDownLabel         matlab.ui.control.Label
        MirrorDropDown              matlab.ui.control.DropDown
        EnvironmentStartup          matlab.ui.control.Button
        EnvironmentShutdown         matlab.ui.control.Button
        EnvironmentLampLabel        matlab.ui.control.Label
        EnvironmentLamp             matlab.ui.control.Lamp
        GimbalStartup               matlab.ui.control.Button
        GimbalShutdown              matlab.ui.control.Button
        GimbalLampLabel             matlab.ui.control.Label
        GimbalLamp                  matlab.ui.control.Lamp
        WaveFrontStartup            matlab.ui.control.Button
        WaveFrontShutdown           matlab.ui.control.Button
        WaveFrontLampLabel          matlab.ui.control.Label
        WaveFrontLamp               matlab.ui.control.Lamp
        DMStartup                   matlab.ui.control.Button
        DMShutdown                  matlab.ui.control.Button
        DMLampLabel                 matlab.ui.control.Label
        DMLamp                      matlab.ui.control.Lamp
        CloseButton                 matlab.ui.control.Button
        OuputSessionDirectoryLabel  matlab.ui.control.Label
        OutputSession               matlab.ui.control.Label
        GimbalInit                  matlab.ui.control.Button
        NewSessionButton            matlab.ui.control.Button
        Log                         matlab.ui.control.TextArea
        RefreshButton               matlab.ui.control.Button
        MonitorSwitch               matlab.ui.control.Switch
        OnlineButton                matlab.ui.control.Button
        OfflineButton               matlab.ui.control.Button
    end


    properties (Access = public)
        onlineColor = [0 1.0 0.0]; % Description
        offlineColor = [1.0, 0.0, 0.0]; 
        pendingColor = [0.95 0.4 0.01];
        bench;
        lastLogBufferIndex = 0;
        firstLogBufferIndex = 0;
        listeners = {};
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            if app.bench.has('environment')
                app.EnvironmentLamp.Color = app.onlineColor;
                app.EnvironmentShutdown.Enable = 'on';
                app.EnvironmentStartup.Enable = 'off';
            
            else
                app.EnvironmentLamp.Color = app.offlineColor;
                app.EnvironmentShutdown.Enable = 'off';
                app.EnvironmentStartup.Enable = 'on';
            end
            
            if ~app.bench.isDm
                app.GimbalLamp.Color = app.offlineColor;
                app.GimbalShutdown.Enable = 'off';
                app.GimbalStartup.Enable = 'off';
                app.GimbalInit.Enable = 'off';
                app.DMLamp.Color = app.offlineColor;
                app.DMShutdown.Enable = 'off';
                app.DMStartup.Enable = 'off';
            else
                if app.bench.has('gimbal')
                    if app.bench.isProcessRunning(naomi.KEYS.P_INITGIMBAL);
                        app.GimbalLamp.Color = app.pendingColor;
                        app.GimbalShutdown.Enable = 'on';
                        app.GimbalStartup.Enable = 'off';
                        app.GimbalInit.Enable = 'on';
                    else
                        app.GimbalLamp.Color = app.onlineColor;
                        app.GimbalShutdown.Enable = 'on';
                        app.GimbalStartup.Enable = 'off';
                        app.GimbalInit.Enable = 'on';
                    end
                else
                    app.GimbalLamp.Color = app.offlineColor;
                    app.GimbalShutdown.Enable = 'off';
                    app.GimbalStartup.Enable = 'on';
                    app.GimbalInit.Enable = 'off';
                end
                
                if app.bench.has('dm')
                    app.DMLamp.Color = app.onlineColor;
                    app.DMShutdown.Enable = 'on';
                    app.DMStartup.Enable = 'off';
                    

                else
                    app.DMLamp.Color = app.offlineColor;
                    app.DMShutdown.Enable = 'off';
                    app.DMStartup.Enable = 'on';
                end
                
         
            end
            
            if app.bench.has('wfs')
                    app.WaveFrontLamp.Color = app.onlineColor;
                    app.WaveFrontShutdown.Enable = 'on';
                    app.WaveFrontStartup.Enable = 'off';
                    

            else
                    app.WaveFrontLamp.Color = app.offlineColor;
                    app.WaveFrontShutdown.Enable = 'off';
                    app.WaveFrontStartup.Enable = 'on';
            end
            
            app.OutputSession.Text = app.bench.config.sessionDirectory;
            try
                app.MirrorDropDown.Value = app.bench.dmId;
            catch EM
            end
            nNewLog =app.bench.logBuffer.index-app.lastLogBufferIndex;
            if nNewLog>0 && app.bench.logBuffer.index>0
                iStart = max([1, app.firstLogBufferIndex, app.bench.logBuffer.index-3]);
                app.Log.Value = app.bench.logBuffer.buffer(iStart:app.bench.logBuffer.index);
%                 txt = app.Log.Value;
%                 for i=1:nNewLog
%                     txt = strcat(txt, '\n', app.bench.logBuffer.buffer(app.lastLogBufferIndex+i));
%                 end
%                 
%                 app.lastLogBufferIndex = app.bench.logBuffer.index;
%                 app.Log.Value= txt;

            end
            
        end
       
        
        function toggleMonitor(app)
                 
                app.setListeners;  
        end
        function toggleOff(app)
            app.MonitorSwitch.Value = 'Off';
            app.clearListeners;
            app.Log.Value = 'Monitor Off';
            
        end

        function setListeners(app)
            app.clearListeners;
            app.listeners = {listener( app.bench, 'logCounter', 'PostSet', @app.refresh), ...
                             listener( app.bench, 'dm', 'PostSet', @app.refresh), ...
                             listener( app.bench, 'gimbal', 'PostSet', @app.refresh), ...
                             listener( app.bench, 'environment', 'PostSet', @app.refresh), ...
                             listener( app.bench, 'processes', 'PostSet', @app.refresh)
                             };
        end
        function clearListeners(app)
            for i=1:length(app.listeners)
                delete(app.listeners{i});
            end
            app.listeners = {};
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, bench)
            global naomiGlobalBench
            if nargin<2
                if ~exist('naomiGlobalBench', 'var') || isempty(naomiGlobalBench)
                    naomiStartup;
                end
                bench = naomiGlobalBench;
            end
            app.bench = bench;
            app.lastLogBufferIndex = app.bench.logBuffer.index;
            app.firstLogBufferIndex = app.lastLogBufferIndex;
            
            app.MirrorDropDown.Items = app.bench.config.dmIdChoices;
            if bench.config.dmId
                app.MirrorDropDown.Value = app.bench.config.dmId;
            end
            app.setListeners; 
            app.refresh();
        end

        % Callback function
        function RefreshButtonPushed(app, event)
            app.refresh;
        end

        % Button pushed function: EnvironmentStartup
        function EnvironmentStartupButtonPushed(app, event)
            app.EnvironmentLamp.Color = app.pendingColor;
            app.bench.startEnvironment();
            
            app.refresh;
        end

        % Button pushed function: EnvironmentShutdown
        function EnvironmentShutdownButtonPushed(app, event)
            app.bench.stopEnvironment();
            
            app.refresh;
        end

        % Button pushed function: GimbalStartup
        function GimbalStartupButtonPushed(app, event)
            app.GimbalLamp.Color = app.pendingColor;
            app.bench.startGimbal();
            app.refresh;
        end

        % Button pushed function: GimbalShutdown
        function GimbalShutdownButtonPushed(app, event)
            app.bench.stopGimbal();
            app.refresh;
        end

        % Button pushed function: WaveFrontStartup
        function WaveFrontStartupButtonPushed(app, event)
            app.WaveFrontLamp.Color = app.pendingColor;
            app.bench.startWfs();
            app.refresh;
        end

        % Button pushed function: WaveFrontShutdown
        function WaveFrontShutdownButtonPushed(app, event)
            app.bench.stopWfs();
            app.refresh;
        end

        % Button pushed function: DMStartup
        function DMStartupButtonPushed(app, event)
            app.DMLamp.Color = app.pendingColor;
            app.bench.startDm();
            app.refresh;
        end

        % Button pushed function: DMShutdown
        function DMShutdownButtonPushed(app, event)
            app.bench.stopDm();
            app.refresh;
        end

        % Value changed function: MirrorDropDown
        function MirrorDropDownValueChanged(app, event)
            value = app.MirrorDropDown.Value;
            
           
            app.bench.changeDm(value);
            app.refresh;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Menu selected function: AlignmentMenu
        function AlignmentMenuSelected(app, event)
            naomi.task.align(app.bench);
            guiFigure = naomi.findGui('Alignment');
            if ~isempty(guiFigure); movegui(guiFigure,'center');end
        end

        % Menu selected function: CalibrationMenu
        function CalibrationMenuSelected(app, event)
            naomi.task.openCalibrationPanel(app.bench);
        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            app.bench.newSession;
        end

        % Button pushed function: GimbalInit
        function GimbalInitButtonPushed(app, event)
            app.GimbalLamp.Color = app.pendingColor;
            pause(0.5); 
            app.bench.initGimbal;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed2(app, event)
            app.refresh;
        end

        % Menu selected function: EngineeringMenu
        function EngineeringMenuSelected(app, event)
            naomi.task.openEngineeringPanel(app.bench);
        end

        % Menu selected function: EnvironmentMenu
        function EnvironmentMenuSelected(app, event)
            naomi.task.openEnvironmentPanel(app.bench);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
            app.toggleOff;
            delete(app);
        end

        % Value changed function: MonitorSwitch
        function MonitorSwitchValueChanged(app, event)
            value = app.MonitorSwitch.Value;
            switch value 
                case 'Off'
                    app.toggleOff;
                case 'Monitor'
                    app.toggleMonitor;
            end
        end

        % Button pushed function: OnlineButton
        function OnlineButtonPushed(app, event)
            app.bench.start;
        end

        % Button pushed function: OfflineButton
        function OfflineButtonPushed(app, event)
            app.bench.stop;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Naomi Startup';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PanelMenu = uimenu(app.UIFigure);
            app.PanelMenu.Text = 'Panels';
            app.AlignmentMenu = uimenu(app.PanelMenu);
            app.AlignmentMenu.MenuSelectedFcn = createCallbackFcn(app, @AlignmentMenuSelected, true);
            app.AlignmentMenu.Text = 'Alignment';
            app.CalibrationMenu = uimenu(app.PanelMenu);
            app.CalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationMenuSelected, true);
            app.CalibrationMenu.Text = 'Calibration';
            app.EnvironmentMenu = uimenu(app.PanelMenu);
            app.EnvironmentMenu.MenuSelectedFcn = createCallbackFcn(app, @EnvironmentMenuSelected, true);
            app.EnvironmentMenu.Text = 'Environment';
            app.EngineeringMenu = uimenu(app.PanelMenu);
            app.EngineeringMenu.MenuSelectedFcn = createCallbackFcn(app, @EngineeringMenuSelected, true);
            app.EngineeringMenu.Text = 'Engineering';
            app.MirrorDropDownLabel = uilabel(app.UIFigure);
            app.MirrorDropDownLabel.Text = 'Mirror';
            app.MirrorDropDown = uidropdown(app.UIFigure);
            app.MirrorDropDown.ValueChangedFcn = createCallbackFcn(app, @MirrorDropDownValueChanged, true);
            app.EnvironmentStartup = uibutton(app.UIFigure, 'push');
            app.EnvironmentStartup.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentStartupButtonPushed, true);
            app.EnvironmentStartup.Text = 'Online';
            app.EnvironmentShutdown = uibutton(app.UIFigure, 'push');
            app.EnvironmentShutdown.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentShutdownButtonPushed, true);
            app.EnvironmentShutdown.Text = 'Offline';
            app.EnvironmentLampLabel = uilabel(app.UIFigure);
            app.EnvironmentLampLabel.Text = 'Environment ';
            app.EnvironmentLamp = uilamp(app.UIFigure);
            app.GimbalStartup = uibutton(app.UIFigure, 'push');
            app.GimbalStartup.ButtonPushedFcn = createCallbackFcn(app, @GimbalStartupButtonPushed, true);
            app.GimbalStartup.Text = 'Online';
            app.GimbalShutdown = uibutton(app.UIFigure, 'push');
            app.GimbalShutdown.ButtonPushedFcn = createCallbackFcn(app, @GimbalShutdownButtonPushed, true);
            app.GimbalShutdown.Text = 'Offline';
            app.GimbalLampLabel = uilabel(app.UIFigure);
            app.GimbalLampLabel.Text = 'Gimbal';
            app.GimbalLamp = uilamp(app.UIFigure);
            app.WaveFrontStartup = uibutton(app.UIFigure, 'push');
            app.WaveFrontStartup.ButtonPushedFcn = createCallbackFcn(app, @WaveFrontStartupButtonPushed, true);
            app.WaveFrontStartup.Text = 'Online';
            app.WaveFrontShutdown = uibutton(app.UIFigure, 'push');
            app.WaveFrontShutdown.ButtonPushedFcn = createCallbackFcn(app, @WaveFrontShutdownButtonPushed, true);
            app.WaveFrontShutdown.Text = 'Offline';
            app.WaveFrontLampLabel = uilabel(app.UIFigure);
            app.WaveFrontLampLabel.Text = 'Wave Front';
            app.WaveFrontLamp = uilamp(app.UIFigure);
            app.DMStartup = uibutton(app.UIFigure, 'push');
            app.DMStartup.ButtonPushedFcn = createCallbackFcn(app, @DMStartupButtonPushed, true);
            app.DMStartup.Text = 'Online';
            app.DMShutdown = uibutton(app.UIFigure, 'push');
            app.DMShutdown.ButtonPushedFcn = createCallbackFcn(app, @DMShutdownButtonPushed, true);
            app.DMShutdown.Text = 'Offline';
            app.DMLampLabel = uilabel(app.UIFigure);
            app.DMLampLabel.Text = 'DM';
            app.DMLamp = uilamp(app.UIFigure);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OuputSessionDirectoryLabel = uilabel(app.UIFigure);
            app.OuputSessionDirectoryLabel.Text = 'Ouput Session Directory :';
            app.OutputSession = uilabel(app.UIFigure);
            app.OutputSession.Text = '';
            app.GimbalInit = uibutton(app.UIFigure, 'push');
            app.GimbalInit.ButtonPushedFcn = createCallbackFcn(app, @GimbalInitButtonPushed, true);
            app.GimbalInit.Text = 'Init';
            app.NewSessionButton = uibutton(app.UIFigure, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Text = 'New Session';
            app.Log = uitextarea(app.UIFigure);
            app.Log.Editable = 'off';
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed2, true);
            app.RefreshButton.Text = 'Refresh';
            app.MonitorSwitch = uiswitch(app.UIFigure, 'slider');
            app.MonitorSwitch.Items = {'Off', 'Monitor'};
            app.MonitorSwitch.ValueChangedFcn = createCallbackFcn(app, @MonitorSwitchValueChanged, true);
            app.MonitorSwitch.Value = 'Monitor';
            app.OnlineButton = uibutton(app.UIFigure, 'push');
            app.OnlineButton.ButtonPushedFcn = createCallbackFcn(app, @OnlineButtonPushed, true);
            app.OnlineButton.Text = 'Online';
            app.OfflineButton = uibutton(app.UIFigure, 'push');
            app.OfflineButton.ButtonPushedFcn = createCallbackFcn(app, @OfflineButtonPushed, true);
            app.OfflineButton.Text = 'Offline';
        end
    end

    methods (Access = public)

        % Construct app
        function app = startupGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end