classdef ztpExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DataInfo                matlab.ui.control.Label
        CloseButton             matlab.ui.control.Button
        QCPanel                 matlab.ui.container.Panel
        UIResidualVector        matlab.ui.control.UIAxes
        UIGainVector            matlab.ui.control.UIAxes
        UIMode                  matlab.ui.control.UIAxes
        ShowNzerEditFieldLabel  matlab.ui.control.Label
        ShowNzerEditField       matlab.ui.control.NumericEditField
        TabGroup                matlab.ui.container.TabGroup
        ModebyModeTab           matlab.ui.container.Tab
        UIZernike               matlab.ui.control.UIAxes
        UIZernikeInfo           matlab.ui.control.UIAxes
        UIPhase                 matlab.ui.control.UIAxes
        UIResidual              matlab.ui.control.UIAxes
        PreviousZernike         matlab.ui.control.Button
        NextZernike             matlab.ui.control.Button
        ZernikeEditFieldLabel   matlab.ui.control.Label
        ZernikeNumber           matlab.ui.control.NumericEditField
        PlotMode                matlab.ui.control.Button
        UIPhaseCut              matlab.ui.control.UIAxes
        UIPhaseResCut           matlab.ui.control.UIAxes
        ModesTab                matlab.ui.container.Tab
        UIM_1                   matlab.ui.control.UIAxes
        UIM_2                   matlab.ui.control.UIAxes
        UIM_3                   matlab.ui.control.UIAxes
        UIM_4                   matlab.ui.control.UIAxes
        UIM_5                   matlab.ui.control.UIAxes
        UIM_6                   matlab.ui.control.UIAxes
        UIM_7                   matlab.ui.control.UIAxes
        UIM_8                   matlab.ui.control.UIAxes
        UIM_9                   matlab.ui.control.UIAxes
        UIM_10                  matlab.ui.control.UIAxes
        UIM_11                  matlab.ui.control.UIAxes
        UIM_12                  matlab.ui.control.UIAxes
        UIM_13                  matlab.ui.control.UIAxes
        UIM_14                  matlab.ui.control.UIAxes
        UIM_15                  matlab.ui.control.UIAxes
        UIM_16                  matlab.ui.control.UIAxes
        UIM_17                  matlab.ui.control.UIAxes
        UIM_18                  matlab.ui.control.UIAxes
        UIM_19                  matlab.ui.control.UIAxes
        UIM_20                  matlab.ui.control.UIAxes
        UIM_21                  matlab.ui.control.UIAxes
        UIM_22                  matlab.ui.control.UIAxes
        UIM_23                  matlab.ui.control.UIAxes
        UIM_24                  matlab.ui.control.UIAxes
        UIM_25                  matlab.ui.control.UIAxes
        UIM_26                  matlab.ui.control.UIAxes
        UIM_27                  matlab.ui.control.UIAxes
        UIM_28                  matlab.ui.control.UIAxes
        UIM_29                  matlab.ui.control.UIAxes
        UIM_30                  matlab.ui.control.UIAxes
        UIM_31                  matlab.ui.control.UIAxes
        UIM_32                  matlab.ui.control.UIAxes
        UIM_33                  matlab.ui.control.UIAxes
        UIM_34                  matlab.ui.control.UIAxes
        UIM_35                  matlab.ui.control.UIAxes
        UIM_36                  matlab.ui.control.UIAxes
        UIM_37                  matlab.ui.control.UIAxes
        UIM_38                  matlab.ui.control.UIAxes
        UIM_39                  matlab.ui.control.UIAxes
        UIM_40                  matlab.ui.control.UIAxes
        UIM_41                  matlab.ui.control.UIAxes
        UIM_42                  matlab.ui.control.UIAxes
        PlotAllModes            matlab.ui.control.Button
        CenteringOffsetPanel    matlab.ui.container.Panel
        OffsetsLabel            matlab.ui.control.Label
        ScaleFactorLabel        matlab.ui.control.Label
        Scale                   matlab.ui.control.Slider
        Center                  matlab.ui.control.Button
        OffsetStep              matlab.ui.control.NumericEditField
        XOffsetPlus             matlab.ui.control.Button
        XOffsetMinus            matlab.ui.control.Button
        YOffsetPlus             matlab.ui.control.Button
        YOffsetMinus            matlab.ui.control.Button
        AngleOffsetKnob         matlab.ui.control.Knob
        AngleOffsetField        matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        ZtPData; % The ZtPData 
        curentZernike = 1;
    end

    methods (Access = public)
    
        function refresh(app)
            if app.TabGroup.SelectedTab == app.ModebyModeTab
                app.ZernikeNumber.Value = app.curentZernike;
                
                phaseData = app.ZtPData.phaseDataOfZernike(app.curentZernike);
                phaseData.plotAll( {app.UIPhase,app.UIResidual, app.UIZernikeInfo, app.UIZernike, app.UIPhaseCut, app.UIPhaseResCut}); 
%             phaseData.plot(app.UIPhase);
%             hold(app.UIPhase, 'on');
%                 phaseData.plotPupill(app.UIPhase);
%             hold(app.UIPhase, 'off');
%             phaseData.residuals.plot(app.UIResidual);
%             
%             zernikeData = phaseData.zernike();
%             zernikeData.plotInfo(app.UIZernikeInfo);
%             zernikeData.plotPhase(app.UIZernike);
            else
                axesList = {};
                for i=1:42
                    axesList{i} = app.(sprintf('UIM_%d',i));
                end
                app.ZtPData.plotModes(axesList);
            end
            
            
            app.ZtPData.plotQc({app.UIResidualVector, app.UIGainVector, app.UIMode});
            maskMM = naomi.getFromData.ztcMask(app.ZtPData, 'mm');
            maskPX = naomi.getFromData.ztcMask(app.ZtPData, 'pixel');
            app.OffsetsLabel.Text = sprintf('Center at %.3f %.3f pupill of %.1fmm -> %.1fpixel', ...
                                            app.ZtPData.xCenter,app.ZtPData.yCenter, ...
                                            maskMM{1}, maskPX{1});
            app.Scale.Value = app.ZtPData.getKey(naomi.KEYS.PIXSCALEFACTOR, 1.0);                          
            app.DataInfo.Text = app.ZtPData.shortInfo;
            
            adeg = (app.ZtPData.getKey(naomi.KEYS.DMANGLE, 0.0)+app.ZtPData.getKey(naomi.KEYS.DMANGLEOFFSET, 0.0))*180/pi;
            app.AngleOffsetField.Value = adeg;
            app.AngleOffsetKnob.Value =  adeg;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ZtPData)
            app.ZtPData = ZtPData;
            app.curentZernike = ZtPData.firstZernike;
            app.ZernikeNumber.Value = app.curentZernike;
            
            app.ZernikeNumber.Limits = [app.ZtPData.firstZernike,app.ZtPData.lastZernike];
            
            
            app.refresh();
        end

        % Value changed function: ZernikeNumber
        function ZernikeNumberValueChanged(app, event)
            value = app.ZernikeNumber.Value;
            value = app.ZernikeNumber.Value;
            app.curentZernike = value;
            app.refresh();
        end

        % Button pushed function: PreviousZernike
        function PreviousZernikeButtonPushed(app, event)
            l = app.ZernikeNumber.Limits;
            if app.curentZernike>l(1);
                app.curentZernike = app.curentZernike -1;
                app.refresh();
            end
        end

        % Button pushed function: NextZernike
        function NextZernikeButtonPushed(app, event)
            l = app.ZernikeNumber.Limits;
            
            if app.curentZernike<l(2);
                app.curentZernike = app.curentZernike +1;
                app.refresh();
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Value changed function: ShowNzerEditField
        function ShowNzerEditFieldValueChanged(app, event)
            value = app.ShowNzerEditField.Value;
            app.ZtPData.maxZernike = value;
            app.refresh();
        end

        % Callback function
        function PlotAllButtonPushed(app, event)
            naomi.plot.figure('ZtP');
            app.ZtPData.plotModes();
        end

        % Callback function
        function xCenterValueChanged(app, event)
            value = app.xCenter.Value;
            app.ZtPData.xReCenter = value;
            app.refresh();
        end

        % Callback function
        function yCenterValueChanged(app, event)
            value = app.yCenter.Value;
            app.ZtPData.yReCenter = value;
            app.refresh();
        end

        % Callback function
        function PuppDiameterValueChanged(app, event)
            value = app.PuppDiameter.Value;
            app.ZtPData.pupReSize = value/1000;
            app.refresh();
        end

        % Callback function
        function AsMeasuredValueChanged(app, event)
            value = app.AsMeasured.Value;
            app.ZtPData.reScale = ~value;
            app.refresh();
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.refresh();
        end

        % Button pushed function: XOffsetPlus
        function XOffsetPlusButtonPushed(app, event)
            offset = app.OffsetStep.Value;
            app.ZtPData.setKey(naomi.KEYS.XOFFSET, app.ZtPData.getKey(naomi.KEYS.XOFFSET, 0.0)+offset);
            app.refresh();
        end

        % Button pushed function: XOffsetMinus
        function XOffsetMinusButtonPushed(app, event)
            offset = app.OffsetStep.Value;
            app.ZtPData.setKey(naomi.KEYS.XOFFSET, app.ZtPData.getKey(naomi.KEYS.XOFFSET, 0.0)-offset);
            app.refresh();
        end

        % Button pushed function: YOffsetPlus
        function YOffsetPlusButtonPushed(app, event)
            offset = app.OffsetStep.Value;
            app.ZtPData.setKey(naomi.KEYS.YOFFSET, app.ZtPData.getKey(naomi.KEYS.YOFFSET, 0.0)+offset);
            app.refresh();
        end

        % Button pushed function: YOffsetMinus
        function YOffsetMinusButtonPushed(app, event)
            offset = app.OffsetStep.Value;
            app.ZtPData.setKey(naomi.KEYS.YOFFSET, app.ZtPData.getKey(naomi.KEYS.YOFFSET, 0.0)-offset);
            app.refresh();
        end

        % Button pushed function: Center
        function CenterButtonPushed(app, event)
            app.ZtPData.setKey(naomi.KEYS.XOFFSET, 0.0);
            app.ZtPData.setKey(naomi.KEYS.YOFFSET, 0.0);
            app.ZtPData.setKey(naomi.KEYS.PIXSCALEFACTOR, 1.0);
            app.ZtPData.setKey(naomi.KEYS.DMANGLEOFFSET, 0.0);
            app.refresh();
        end

        % Value changed function: Scale
        function ScaleValueChanged(app, event)
            value = app.Scale.Value;
            app.ZtPData.setKey(naomi.KEYS.PIXSCALEFACTOR, value);
            app.refresh();
        end

        % Button pushed function: PlotMode
        function PlotModeButtonPushed(app, event)
            naomi.plot.figure(sprintf('ZtP Mode %d',app.curentZernike));
            phaseData = app.ZtPData.phaseDataOfZernike(app.curentZernike);
            phaseData.plotAll;
        end

        % Button pushed function: PlotAllModes
        function PlotAllModesButtonPushed(app, event)
            naomi.plot.figure('ZtP Modes');
            app.ZtPData.plotModes;
        end

        % Value changed function: AngleOffsetKnob
        function AngleOffsetKnobValueChanged(app, event)
            value = app.AngleOffsetKnob.Value;
            app.ZtPData.setKey(naomi.KEYS.DMANGLEOFFSET, value*pi/180);
            app.refresh();
        end

        % Value changed function: AngleOffsetField
        function AngleOffsetFieldValueChanged(app, event)
            value = app.AngleOffsetField.Value;
            app.ZtPData.setKey(naomi.KEYS.DMANGLEOFFSET, value*pi/180);
            app.refresh();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'ZtP Explorer';
            app.DataInfo = uilabel(app.UIFigure);
            app.DataInfo.Text = '--------';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.QCPanel = uipanel(app.UIFigure);
            app.QCPanel.Title = 'QC';
            app.UIResidualVector = uiaxes(app.QCPanel);
            app.UIGainVector = uiaxes(app.QCPanel);
            app.UIMode = uiaxes(app.QCPanel);
            app.ShowNzerEditFieldLabel = uilabel(app.UIFigure);
            app.ShowNzerEditFieldLabel.Text = 'Show Nzer';
            app.ShowNzerEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowNzerEditField.Limits = [1 21];
            app.ShowNzerEditField.ValueChangedFcn = createCallbackFcn(app, @ShowNzerEditFieldValueChanged, true);
            app.ShowNzerEditField.Value = 21;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ModebyModeTab = uitab(app.TabGroup);
            app.ModebyModeTab.Title = 'Mode by Mode';
            app.UIZernike = uiaxes(app.ModebyModeTab);
            app.UIZernikeInfo = uiaxes(app.ModebyModeTab);
            app.UIPhase = uiaxes(app.ModebyModeTab);
            title(app.UIPhase, 'Phase')
            app.UIResidual = uiaxes(app.ModebyModeTab);
            title(app.UIResidual, 'Residual')
            app.PreviousZernike = uibutton(app.ModebyModeTab, 'push');
            app.PreviousZernike.ButtonPushedFcn = createCallbackFcn(app, @PreviousZernikeButtonPushed, true);
            app.PreviousZernike.Text = '-';
            app.NextZernike = uibutton(app.ModebyModeTab, 'push');
            app.NextZernike.ButtonPushedFcn = createCallbackFcn(app, @NextZernikeButtonPushed, true);
            app.NextZernike.Text = '+';
            app.ZernikeEditFieldLabel = uilabel(app.ModebyModeTab);
            app.ZernikeEditFieldLabel.Text = 'Zernike';
            app.ZernikeNumber = uieditfield(app.ModebyModeTab, 'numeric');
            app.ZernikeNumber.Limits = [0 Inf];
            app.ZernikeNumber.ValueDisplayFormat = '%.0f';
            app.ZernikeNumber.ValueChangedFcn = createCallbackFcn(app, @ZernikeNumberValueChanged, true);
            app.PlotMode = uibutton(app.ModebyModeTab, 'push');
            app.PlotMode.ButtonPushedFcn = createCallbackFcn(app, @PlotModeButtonPushed, true);
            app.PlotMode.Text = '';
            app.UIPhaseCut = uiaxes(app.ModebyModeTab);
            app.UIPhaseResCut = uiaxes(app.ModebyModeTab);
            app.ModesTab = uitab(app.TabGroup);
            app.ModesTab.Title = '21 Modes';
            app.UIM_1 = uiaxes(app.ModesTab);
            app.UIM_2 = uiaxes(app.ModesTab);
            app.UIM_3 = uiaxes(app.ModesTab);
            app.UIM_4 = uiaxes(app.ModesTab);
            app.UIM_5 = uiaxes(app.ModesTab);
            app.UIM_6 = uiaxes(app.ModesTab);
            app.UIM_7 = uiaxes(app.ModesTab);
            app.UIM_8 = uiaxes(app.ModesTab);
            app.UIM_9 = uiaxes(app.ModesTab);
            app.UIM_10 = uiaxes(app.ModesTab);
            app.UIM_11 = uiaxes(app.ModesTab);
            app.UIM_12 = uiaxes(app.ModesTab);
            app.UIM_13 = uiaxes(app.ModesTab);
            app.UIM_14 = uiaxes(app.ModesTab);
            app.UIM_15 = uiaxes(app.ModesTab);
            app.UIM_16 = uiaxes(app.ModesTab);
            app.UIM_17 = uiaxes(app.ModesTab);
            app.UIM_18 = uiaxes(app.ModesTab);
            app.UIM_19 = uiaxes(app.ModesTab);
            app.UIM_20 = uiaxes(app.ModesTab);
            app.UIM_21 = uiaxes(app.ModesTab);
            app.UIM_22 = uiaxes(app.ModesTab);
            app.UIM_23 = uiaxes(app.ModesTab);
            app.UIM_24 = uiaxes(app.ModesTab);
            app.UIM_25 = uiaxes(app.ModesTab);
            app.UIM_26 = uiaxes(app.ModesTab);
            app.UIM_27 = uiaxes(app.ModesTab);
            app.UIM_28 = uiaxes(app.ModesTab);
            app.UIM_29 = uiaxes(app.ModesTab);
            app.UIM_30 = uiaxes(app.ModesTab);
            app.UIM_31 = uiaxes(app.ModesTab);
            app.UIM_32 = uiaxes(app.ModesTab);
            app.UIM_33 = uiaxes(app.ModesTab);
            app.UIM_34 = uiaxes(app.ModesTab);
            app.UIM_35 = uiaxes(app.ModesTab);
            app.UIM_36 = uiaxes(app.ModesTab);
            app.UIM_37 = uiaxes(app.ModesTab);
            app.UIM_38 = uiaxes(app.ModesTab);
            app.UIM_39 = uiaxes(app.ModesTab);
            app.UIM_40 = uiaxes(app.ModesTab);
            app.UIM_41 = uiaxes(app.ModesTab);
            app.UIM_42 = uiaxes(app.ModesTab);
            app.PlotAllModes = uibutton(app.ModesTab, 'push');
            app.PlotAllModes.ButtonPushedFcn = createCallbackFcn(app, @PlotAllModesButtonPushed, true);
            app.PlotAllModes.Text = '';
            app.CenteringOffsetPanel = uipanel(app.UIFigure);
            app.CenteringOffsetPanel.Title = 'Centering Offset';
            app.OffsetsLabel = uilabel(app.CenteringOffsetPanel);
            app.OffsetsLabel.Text = 'Offsets';
            app.ScaleFactorLabel = uilabel(app.CenteringOffsetPanel);
            app.ScaleFactorLabel.Text = 'Scale Factor';
            app.Scale = uislider(app.CenteringOffsetPanel);
            app.Scale.Limits = [0 2];
            app.Scale.MajorTicks = [0 0.5 1 1.5 2];
            app.Scale.ValueChangedFcn = createCallbackFcn(app, @ScaleValueChanged, true);
            app.Scale.MinorTicks = [];
            app.Scale.Value = 1;
            app.Center = uibutton(app.CenteringOffsetPanel, 'push');
            app.Center.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.Center.Text = 'Center';
            app.OffsetStep = uieditfield(app.CenteringOffsetPanel, 'numeric');
            app.OffsetStep.Value = 0.1;
            app.XOffsetPlus = uibutton(app.CenteringOffsetPanel, 'push');
            app.XOffsetPlus.ButtonPushedFcn = createCallbackFcn(app, @XOffsetPlusButtonPushed, true);
            app.XOffsetPlus.Text = '+';
            app.XOffsetMinus = uibutton(app.CenteringOffsetPanel, 'push');
            app.XOffsetMinus.ButtonPushedFcn = createCallbackFcn(app, @XOffsetMinusButtonPushed, true);
            app.XOffsetMinus.Text = '-';
            app.YOffsetPlus = uibutton(app.CenteringOffsetPanel, 'push');
            app.YOffsetPlus.ButtonPushedFcn = createCallbackFcn(app, @YOffsetPlusButtonPushed, true);
            app.YOffsetPlus.Text = '+';
            app.YOffsetMinus = uibutton(app.CenteringOffsetPanel, 'push');
            app.YOffsetMinus.ButtonPushedFcn = createCallbackFcn(app, @YOffsetMinusButtonPushed, true);
            app.YOffsetMinus.Text = '-';
            app.AngleOffsetKnob = uiknob(app.CenteringOffsetPanel, 'continuous');
            app.AngleOffsetKnob.Limits = [-5 5];
            app.AngleOffsetKnob.ValueChangedFcn = createCallbackFcn(app, @AngleOffsetKnobValueChanged, true);
            app.AngleOffsetKnob.MinorTicks = [];
            app.AngleOffsetField = uieditfield(app.CenteringOffsetPanel, 'numeric');
            app.AngleOffsetField.ValueChangedFcn = createCallbackFcn(app, @AngleOffsetFieldValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ztpExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end