classdef dmBiasExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIDMContour  matlab.ui.control.UIAxes
        Close        matlab.ui.control.Button
        UIDMact      matlab.ui.control.UIAxes
        DataInfo     matlab.ui.control.Label
        UIDMImg      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        dmBiasData; % Description
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            app.dmBiasData.plotImage(app.UIDMImg);
            app.dmBiasData.plotContour(app.UIDMContour);
            app.dmBiasData.plot(app.UIDMact);
            app.DataInfo.Text = app.dmBiasData.shortInfo; 
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dmBiasData)
            app.dmBiasData = dmBiasData;
            app.refresh();
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'DM Bias Explorer';
            app.UIDMContour = uiaxes(app.UIFigure);
            app.Close = uibutton(app.UIFigure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = 'Close';
            app.UIDMact = uiaxes(app.UIFigure);
            app.DataInfo = uilabel(app.UIFigure);
            app.UIDMImg = uiaxes(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = dmBiasExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end