classdef environmentControlGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnvironmentControl              matlab.ui.Figure
        GoToCalibTemperatureButton      matlab.ui.control.Button
        MaintainTemperatureCalibModeButton  matlab.ui.control.Button
        TurnOffButton                   matlab.ui.control.Button
        UIAxesCURRENT                   matlab.ui.control.UIAxes
        UIAxesFAN                       matlab.ui.control.UIAxes
        RefreshButton                   matlab.ui.control.Button
        UIAxesTemp                      matlab.ui.control.UIAxes
        CloseButton                     matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        CurrentValue                    matlab.ui.control.Label
        PeltierCurrentLabel             matlab.ui.control.Label
        Fan1Value                       matlab.ui.control.Label
        InnerFanLabel                   matlab.ui.control.Label
        OuterFanLabel                   matlab.ui.control.Label
        Fan2Value                       matlab.ui.control.Label
        InnerTempregulLabel             matlab.ui.control.Label
        OutValue                        matlab.ui.control.Label
        InValue                         matlab.ui.control.Label
        OuterTempLabel                  matlab.ui.control.Label
        MirrorValue                     matlab.ui.control.Label
        MirrorTempLabel                 matlab.ui.control.Label
        QSMValue                        matlab.ui.control.Label
        QSMTempLabel                    matlab.ui.control.Label
        SafeToOpenLampLabel             matlab.ui.control.Label
        SafeToOpen                      matlab.ui.control.Lamp
        ReadyForDMCalibrationLampLabel  matlab.ui.control.Label
        ReadyForDMCalibration           matlab.ui.control.Lamp
        SafeToOpenLegend                matlab.ui.control.TextArea
        ReadyForDMCalibrationLegend     matlab.ui.control.TextArea
        ManualModeOnlyPanel             matlab.ui.container.Panel
        SetPointEditFieldLabel          matlab.ui.control.Label
        SetPoint                        matlab.ui.control.NumericEditField
        InnerFanEditFieldLabel          matlab.ui.control.Label
        InnerFan                        matlab.ui.control.NumericEditField
        OuterFanLabel_2                 matlab.ui.control.Label
        OuterFan                        matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        VLabel_2                        matlab.ui.control.Label
        SetButton                       matlab.ui.control.Button
        GoToEmbiantTemperatureButton    matlab.ui.control.Button
        ManualButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        environment; % naomi.objects.Environment object
        buffer; % naomi.objects.EnvironmentBuffer object
        appTimer;
        firstTimeManual = 1;
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            app.environment.updateControl();
            app.buffer.update(app.environment);
            
            switch app.environment.controlState
                case app.environment.MANUAL
                    app.SetPoint.Enable = 'on';
                    app.InnerFan.Enable = 'on';
                    app.OuterFan.Enable = 'on';
                    app.SetButton.Enable = 'on';
                otherwise
                    app.SetPoint.Enable = 'off';
                    app.InnerFan.Enable = 'off';
                    app.OuterFan.Enable = 'off';
                    app.SetButton.Enable = 'off';
            end
            
            if ~app.buffer.index
                return 
            end
            
            time = app.buffer.get('time');
            
            time = (time-time(1))*24*3600; % time in seconds 
            
            c = app.buffer.get('current');
            f1 = app.buffer.get('fan1');
            f2 = app.buffer.get('fan2');
            t1 = app.buffer.get('temp1');
            t2 = app.buffer.get('temp2');
            t4 = app.buffer.get('temp4');
            t5 = app.buffer.get('temp5');
            t6 = app.buffer.get('temp6');
            rt = app.buffer.get('regulTemp');
            plot(app.UIAxesCURRENT, time, c);
            
            plot(app.UIAxesFAN, time, f1);
            hold(app.UIAxesFAN, 'on');
            plot(app.UIAxesFAN, time, f2);
            hold(app.UIAxesFAN, 'off');
            
            plot(app.UIAxesTemp, time, t2, 'g-',  'DisplayName','peltier out');
            hold(app.UIAxesTemp, 'on');
            plot(app.UIAxesTemp, time, t1, 'b-', 'DisplayName','peltier in');
            plot(app.UIAxesTemp, time, rt, 'b:', 'DisplayName','regul setpoint');
            
            plot(app.UIAxesTemp, time, t4, 'k:', 'DisplayName', 'embiant');
            
            plot(app.UIAxesTemp, time, t5, 'r-', 'DisplayName', 'mirror');
            plot(app.UIAxesTemp, time, t6, 'r:', 'DisplayName', 'qsm');
            legend(app.UIAxesTemp, 'Location','southwest');
            legend(app.UIAxesTemp, 'boxoff');
            
            hold(app.UIAxesTemp, 'off');
            
            fmt = '%.2f C';
            
            app.CurrentValue.Text = sprintf('%.2f A', c(end));
            app.Fan1Value.Text = sprintf('%.2f V', f1(end));
            app.Fan2Value.Text = sprintf('%.2f V', f2(end));
            app.OutValue.Text = sprintf(fmt, t2(end));
            app.InValue.Text = sprintf(fmt, t1(end));
            app.MirrorValue.Text = sprintf(fmt, t5(end));
            app.QSMValue.Text = sprintf(fmt, t6(end));
            
            
            [test, expl] = app.environment.isSafeToOpen;
            if test
                app.SafeToOpen.Color = [0.0 1.0 0.0];
            else
                app.SafeToOpen.Color = [1.0 0.0 0.0];
            end
            app.SafeToOpenLegend.Value = expl;
            
            [test, expl] = app.environment.isReadyToCalib;
            if test
                app.ReadyForDMCalibration.Color = [0.0 1.0 0.0];
            else
                app.ReadyForDMCalibration.Color = [1.0 0.0 0.0];
            end
            app.ReadyForDMCalibrationLegend.Value = expl;
            
                    
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, environment, buffer)
            if nargin<3
                buffer = environment.createBuffer(10000,2500,1);
            end
            app.environment = environment;
            app.buffer = buffer;
            app.appTimer = timer('Period',2.0,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            if app.environment.controlState == app.environment.MANUAL
                % set the right value in edit fields 
                app.ManualButtonPushed(0);
            end
            app.appTimer.TimerFcn = @app.refresh;
            
            
            start(app.appTimer);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refresh();
        end

        % Button pushed function: GoToCalibTemperatureButton
        function GoToCalibTemperatureButtonPushed(app, event)
            app.environment.goToCalib();
        end

        % Button pushed function: MaintainTemperatureCalibModeButton
        function MaintainTemperatureCalibModeButtonPushed(app, event)
           app.environment.calib(); 
        end

        % Button pushed function: TurnOffButton
        function TurnOffButtonPushed(app, event)
           app.environment.turnOff();
        end

        % Close request function: EnvironmentControl
        function EnvironmentControlCloseRequest(app, event)
            stop(app.appTimer);
            delete(app.appTimer);
            delete(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.EnvironmentControl);
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
            [tempRegul, fanIn, fanOut] = app.environment.manual();
            app.SetPoint.Value = tempRegul;
            app.InnerFan.Value = fanIn;
            app.OuterFan.Value = fanOut; 
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.environment.manual(app.SetPoint.Value, app.InnerFan.Value, app.OuterFan.Value);
        end

        % Button pushed function: GoToEmbiantTemperatureButton
        function GoToEmbiantTemperatureButtonPushed(app, event)
            app.environment.goToEmbiant();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnvironmentControl = uifigure;
            app.EnvironmentControl.Name = 'Environment Control';
            app.EnvironmentControl.CloseRequestFcn = createCallbackFcn(app, @EnvironmentControlCloseRequest, true);
            app.GoToCalibTemperatureButton = uibutton(app.EnvironmentControl, 'push');
            app.GoToCalibTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @GoToCalibTemperatureButtonPushed, true);
            app.GoToCalibTemperatureButton.Text = 'Go To Calib Temperature';
            app.MaintainTemperatureCalibModeButton = uibutton(app.EnvironmentControl, 'push');
            app.MaintainTemperatureCalibModeButton.ButtonPushedFcn = createCallbackFcn(app, @MaintainTemperatureCalibModeButtonPushed, true);
            app.MaintainTemperatureCalibModeButton.Text = 'Maintain Temperature  (Calib Mode)';
            app.TurnOffButton = uibutton(app.EnvironmentControl, 'push');
            app.TurnOffButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffButtonPushed, true);
            app.TurnOffButton.Text = 'Turn Off';
            app.UIAxesCURRENT = uiaxes(app.EnvironmentControl);
            title(app.UIAxesCURRENT, 'Peltier Current')
            ylabel(app.UIAxesCURRENT, 'current')
            app.UIAxesFAN = uiaxes(app.EnvironmentControl);
            title(app.UIAxesFAN, 'Fans')
            ylabel(app.UIAxesFAN, 'Voltage')
            app.RefreshButton = uibutton(app.EnvironmentControl, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.UIAxesTemp = uiaxes(app.EnvironmentControl);
            title(app.UIAxesTemp, 'Temperatures')
            xlabel(app.UIAxesTemp, 'Time (s)')
            ylabel(app.UIAxesTemp, 'Temp C')
            app.CloseButton = uibutton(app.EnvironmentControl, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel = uipanel(app.EnvironmentControl);
            app.CurrentValue = uilabel(app.Panel);
            app.CurrentValue.Text = '0.0';
            app.PeltierCurrentLabel = uilabel(app.Panel);
            app.PeltierCurrentLabel.Text = 'Peltier Current';
            app.Fan1Value = uilabel(app.Panel);
            app.Fan1Value.Text = '0.0';
            app.InnerFanLabel = uilabel(app.Panel);
            app.InnerFanLabel.Text = 'Inner Fan';
            app.OuterFanLabel = uilabel(app.Panel);
            app.OuterFanLabel.Text = 'Outer Fan';
            app.Fan2Value = uilabel(app.Panel);
            app.Fan2Value.Text = '0.0';
            app.InnerTempregulLabel = uilabel(app.Panel);
            app.InnerTempregulLabel.Text = 'Inner Temp (regul)';
            app.OutValue = uilabel(app.Panel);
            app.OutValue.Text = '0.0';
            app.InValue = uilabel(app.Panel);
            app.InValue.Text = '0.0';
            app.OuterTempLabel = uilabel(app.Panel);
            app.OuterTempLabel.Text = 'Outer Temp.';
            app.MirrorValue = uilabel(app.Panel);
            app.MirrorValue.Text = '0.0';
            app.MirrorTempLabel = uilabel(app.Panel);
            app.MirrorTempLabel.Text = 'Mirror Temp.';
            app.QSMValue = uilabel(app.Panel);
            app.QSMValue.Text = '0.0';
            app.QSMTempLabel = uilabel(app.Panel);
            app.QSMTempLabel.Text = 'QSM Temp.';
            app.SafeToOpenLampLabel = uilabel(app.Panel);
            app.SafeToOpenLampLabel.Text = 'Safe To Open';
            app.SafeToOpen = uilamp(app.Panel);
            app.ReadyForDMCalibrationLampLabel = uilabel(app.Panel);
            app.ReadyForDMCalibrationLampLabel.Text = 'Ready For DM Calibration';
            app.ReadyForDMCalibration = uilamp(app.Panel);
            app.SafeToOpenLegend = uitextarea(app.Panel);
            app.SafeToOpenLegend.Editable = 'off';
            app.ReadyForDMCalibrationLegend = uitextarea(app.Panel);
            app.ReadyForDMCalibrationLegend.Editable = 'off';
            app.ManualModeOnlyPanel = uipanel(app.EnvironmentControl);
            app.ManualModeOnlyPanel.Title = 'Manual Mode Only';
            app.SetPointEditFieldLabel = uilabel(app.ManualModeOnlyPanel);
            app.SetPointEditFieldLabel.Text = 'Set Point';
            app.SetPoint = uieditfield(app.ManualModeOnlyPanel, 'numeric');
            app.SetPoint.Limits = [-5 40];
            app.InnerFanEditFieldLabel = uilabel(app.ManualModeOnlyPanel);
            app.InnerFanEditFieldLabel.Text = 'Inner Fan';
            app.InnerFan = uieditfield(app.ManualModeOnlyPanel, 'numeric');
            app.InnerFan.Limits = [-24 24];
            app.OuterFanLabel_2 = uilabel(app.ManualModeOnlyPanel);
            app.OuterFanLabel_2.Text = 'Outer Fan';
            app.OuterFan = uieditfield(app.ManualModeOnlyPanel, 'numeric');
            app.OuterFan.Limits = [-24 24];
            app.CLabel = uilabel(app.ManualModeOnlyPanel);
            app.CLabel.Text = 'C';
            app.VLabel = uilabel(app.ManualModeOnlyPanel);
            app.VLabel.Text = 'V';
            app.VLabel_2 = uilabel(app.ManualModeOnlyPanel);
            app.VLabel_2.Text = 'V';
            app.SetButton = uibutton(app.ManualModeOnlyPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.GoToEmbiantTemperatureButton = uibutton(app.EnvironmentControl, 'push');
            app.GoToEmbiantTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @GoToEmbiantTemperatureButtonPushed, true);
            app.GoToEmbiantTemperatureButton.Text = 'Go To Embiant Temperature';
            app.ManualButton = uibutton(app.EnvironmentControl, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
        end
    end

    methods (Access = public)

        % Construct app
        function app = environmentControlGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnvironmentControl)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnvironmentControl)
        end
    end
end