classdef ifmExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IFMExplorerUIFigure     matlab.ui.Figure
        ProfileDropDownLabel    matlab.ui.control.Label
        ProfileDropDown         matlab.ui.control.DropDown
        CloseButton             matlab.ui.control.Button
        DataInfo                matlab.ui.control.Label
        QCPanel                 matlab.ui.container.Panel
        UIMax                   matlab.ui.control.UIAxes
        UISign                  matlab.ui.control.UIAxes
        UIHwhm                  matlab.ui.control.UIAxes
        UIPos                   matlab.ui.control.UIAxes
        Plot                    matlab.ui.control.Button
        ActuatorPanel           matlab.ui.container.Panel
        UIScreenPhase           matlab.ui.control.UIAxes
        UIXProfile              matlab.ui.control.UIAxes
        UIYProfile              matlab.ui.control.UIAxes
        UIResidual              matlab.ui.control.UIAxes
        NextActuator            matlab.ui.control.Button
        PreviousActuator        matlab.ui.control.Button
        ActuatorEditFieldLabel  matlab.ui.control.Label
        ActuatorNumber          matlab.ui.control.NumericEditField
        UITheorical             matlab.ui.control.UIAxes
        PResidual               matlab.ui.control.Button
        PIF                     matlab.ui.control.Button
        PALLIF                  matlab.ui.control.Button
    end


    properties (Access = public)
        IFMData; % The naomi.data.IFMatrix object
        curentActuatorNumber = 1;
    end

    methods (Access = public)
    
        function refresh(app)
            app.ActuatorPanel.Title = sprintf('IF Actuator # %d', app.curentActuatorNumber);
            app.ActuatorNumber.Value = app.curentActuatorNumber;
            
            IFData = app.IFMData.IF(app.curentActuatorNumber);
            IFData.plotAll({app.UIScreenPhase, app.UITheorical,app.UIResidual, app.UIXProfile, app.UIYProfile});
            
            
            app.IFMData.plotQc(app.curentActuatorNumber, {app.UIMax, app.UISign, app.UIHwhm, app.UIPos});
            title(app.UIMax, '');
            xlabel(app.UIMax, '');
            xlabel(app.UISign, '');
            
            [nActuator,~, ~] = size(app.IFMData.data);
            if app.curentActuatorNumber<=1
                app.PreviousActuator.Enable = 'off';
            else
                 app.PreviousActuator.Enable = 'on';
            end
            if app.curentActuatorNumber>=nActuator
                app.NextActuator.Enable = 'off';
            else
                 app.NextActuator.Enable = 'on';
            end
            if isempty(app.IFMData.profileResult)
                app.DataInfo.Text = 'Fitting all profiles can take some time';
            else
                app.DataInfo.Text = app.IFMData.shortInfo;
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IFMData)
            app.IFMData = IFMData;
            [nActuator, ~, ~] = size(IFMData.data);
            app.ActuatorNumber.Limits = [1 nActuator];
            app.curentActuatorNumber = floor(nActuator/2);
            app.ProfileDropDown.Value = IFMData.fitType;
            app.refresh();
            
        end

        % Value changed function: ActuatorNumber
        function ActuatorNumberValueChanged(app, event)
            value = app.ActuatorNumber.Value;
            app.curentActuatorNumber = value;
            app.refresh();
        end

        % Button pushed function: NextActuator
        function NextActuatorButtonPushed(app, event)
            app.curentActuatorNumber = app.curentActuatorNumber + 1;
            app.ActuatorNumber.Value = app.curentActuatorNumber;
            app.refresh();
        end

        % Button pushed function: PreviousActuator
        function PreviousActuatorButtonPushed(app, event)
            app.curentActuatorNumber = app.curentActuatorNumber - 1;
            app.ActuatorNumber.Value = app.curentActuatorNumber;
            app.refresh();
        end

        % Value changed function: ProfileDropDown
        function ProfileDropDownValueChanged(app, event)
            value = app.ProfileDropDown.Value;
            app.IFMData.fitType = value;
            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.IFMExplorerUIFigure);
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            naomi.plot.figure('IFM QC');
            app.IFMData.plotQc();
        end

        % Button pushed function: PResidual
        function PResidualButtonPushed(app, event)
            naomi.plot.figure(sprintf('IF RES %.0f', app.curentActuatorNumber));
            gcf;
            app.IFMData.IF(app.curentActuatorNumber).plotProfileResidual();
        end

        % Button pushed function: PIF
        function PIFButtonPushed(app, event)
            naomi.plot.figure(sprintf('IF %.0f', app.curentActuatorNumber));
            app.IFMData.IF(app.curentActuatorNumber).plotScreenPhase();
        end

        % Button pushed function: PALLIF
        function PALLIFButtonPushed(app, event)
            naomi.plot.figure(sprintf('All IF %.0f', app.curentActuatorNumber));
            app.IFMData.IF(app.curentActuatorNumber).plotAll();
 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IFMExplorerUIFigure = uifigure;
            app.IFMExplorerUIFigure.Name = 'IFM Explorer';
            app.ProfileDropDownLabel = uilabel(app.IFMExplorerUIFigure);
            app.ProfileDropDownLabel.Text = 'Profile';
            app.ProfileDropDown = uidropdown(app.IFMExplorerUIFigure);
            app.ProfileDropDown.Items = {'maximum', 'gauss', 'gaussE', 'gaussER', 'lorentz', 'lorentzE', 'naomi'};
            app.ProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ProfileDropDownValueChanged, true);
            app.ProfileDropDown.Value = 'gauss';
            app.CloseButton = uibutton(app.IFMExplorerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DataInfo = uilabel(app.IFMExplorerUIFigure);
            app.DataInfo.Text = '----------';
            app.QCPanel = uipanel(app.IFMExplorerUIFigure);
            app.QCPanel.Title = 'QC';
            app.UIMax = uiaxes(app.QCPanel);
            app.UISign = uiaxes(app.QCPanel);
            app.UIHwhm = uiaxes(app.QCPanel);
            app.UIPos = uiaxes(app.QCPanel);
            app.Plot = uibutton(app.QCPanel, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = '';
            app.ActuatorPanel = uipanel(app.IFMExplorerUIFigure);
            app.ActuatorPanel.Title = 'Actuator';
            app.UIScreenPhase = uiaxes(app.ActuatorPanel);
            app.UIXProfile = uiaxes(app.ActuatorPanel);
            app.UIYProfile = uiaxes(app.ActuatorPanel);
            app.UIResidual = uiaxes(app.ActuatorPanel);
            app.NextActuator = uibutton(app.ActuatorPanel, 'push');
            app.NextActuator.ButtonPushedFcn = createCallbackFcn(app, @NextActuatorButtonPushed, true);
            app.NextActuator.Text = '+';
            app.PreviousActuator = uibutton(app.ActuatorPanel, 'push');
            app.PreviousActuator.ButtonPushedFcn = createCallbackFcn(app, @PreviousActuatorButtonPushed, true);
            app.PreviousActuator.Text = '-';
            app.ActuatorEditFieldLabel = uilabel(app.ActuatorPanel);
            app.ActuatorEditFieldLabel.Text = 'Actuator';
            app.ActuatorNumber = uieditfield(app.ActuatorPanel, 'numeric');
            app.ActuatorNumber.Limits = [1 9999];
            app.ActuatorNumber.ValueDisplayFormat = '%.0f';
            app.ActuatorNumber.ValueChangedFcn = createCallbackFcn(app, @ActuatorNumberValueChanged, true);
            app.ActuatorNumber.Value = 1;
            app.UITheorical = uiaxes(app.ActuatorPanel);
            app.PResidual = uibutton(app.ActuatorPanel, 'push');
            app.PResidual.ButtonPushedFcn = createCallbackFcn(app, @PResidualButtonPushed, true);
            app.PResidual.Text = '';
            app.PIF = uibutton(app.ActuatorPanel, 'push');
            app.PIF.ButtonPushedFcn = createCallbackFcn(app, @PIFButtonPushed, true);
            app.PIF.Text = '';
            app.PALLIF = uibutton(app.ActuatorPanel, 'push');
            app.PALLIF.ButtonPushedFcn = createCallbackFcn(app, @PALLIFButtonPushed, true);
            app.PALLIF.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ifmExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IFMExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IFMExplorerUIFigure)
        end
    end
end