classdef phaseExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        UIPhase     matlab.ui.control.UIAxes
        UIZernike   matlab.ui.control.UIAxes
        Close       matlab.ui.control.Button
        DataInfo    matlab.ui.control.Label
        RMSLabel    matlab.ui.control.Label
        Rms         matlab.ui.control.Label
        PtVLabel    matlab.ui.control.Label
        Ptv         matlab.ui.control.Label
        FullPupill  matlab.ui.control.CheckBox
    end


    properties (Access = public)
        phaseData; % a naomi.data.Phase object or derived
    end

    methods (Access = public)
    
        function refresh(app, varargin)
            
            
            app.FullPupill.Value;
            if app.FullPupill.Value
                phaseData = app.phaseData;
                app.phaseData.plot(app.UIPhase); 
                hold(app.UIPhase, 'on');
                app.phaseData.plotPupill(app.UIPhase);
                hold(app.UIPhase, 'off');
                
            else
                phaseData = app.phaseData.maskedPhaseData;
                phaseData.plot(app.UIPhase); 
            end
            title(app.UIPhase, '');
            zernikesData = phaseData.zernikesData;
            zernikesData.plot(app.UIZernike);
            app.Rms.Text = sprintf('%.3f', phaseData.std);
            app.Ptv.Text = sprintf('%.3f', phaseData.ptv);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, phaseData)
            app.phaseData = phaseData;
            app.refresh();
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: FullPupill
        function FullPupillValueChanged(app, event)
            value = app.FullPupill.Value;
            app.refresh();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIPhase = uiaxes(app.UIFigure);
            app.UIZernike = uiaxes(app.UIFigure);
            app.Close = uibutton(app.UIFigure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = 'Close';
            app.DataInfo = uilabel(app.UIFigure);
            app.RMSLabel = uilabel(app.UIFigure);
            app.RMSLabel.Text = 'RMS';
            app.Rms = uilabel(app.UIFigure);
            app.Rms.Text = 'val';
            app.PtVLabel = uilabel(app.UIFigure);
            app.PtVLabel.Text = 'PtV';
            app.Ptv = uilabel(app.UIFigure);
            app.Ptv.Text = 'val';
            app.FullPupill = uicheckbox(app.UIFigure);
            app.FullPupill.ValueChangedFcn = createCallbackFcn(app, @FullPupillValueChanged, true);
            app.FullPupill.Text = 'Full Pupill';
            app.FullPupill.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = phaseExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end