classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAlign           matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        TipGaugeLabel     matlab.ui.control.Label
        TipGauge          matlab.ui.control.SemicircularGauge
        TiltGaugeLabel    matlab.ui.control.Label
        TiltGauge         matlab.ui.control.SemicircularGauge
        XYLampLabel       matlab.ui.control.Label
        XYLamp            matlab.ui.control.Lamp
        TipTiltLampLabel  matlab.ui.control.Label
        TipTiltLamp       matlab.ui.control.Lamp
        XGauge            matlab.ui.control.LinearGauge
        YGauge            matlab.ui.control.LinearGauge
        OKButton          matlab.ui.control.Button
        CancelButton      matlab.ui.control.Button
        TipValue          matlab.ui.control.Label
        TiltValue         matlab.ui.control.Label
        XValue            matlab.ui.control.Label
        YValue            matlab.ui.control.Label
        MotorsPanel       matlab.ui.container.Panel
        TipPLus           matlab.ui.control.Button
        TipMinus          matlab.ui.control.Button
        TiltMinus         matlab.ui.control.Button
        TiltPlus          matlab.ui.control.Button
        EditField         matlab.ui.control.NumericEditField
        AutoAlignButton   matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        TipHome           matlab.ui.control.Button
        TiltHome          matlab.ui.control.Button
    end


    properties (Access = public)
        appTimer; % timer to refresh the app
        bench;
    end

    methods (Access = public)
    
        function refresh(app, t, e, f)
            bench = app.bench;
            %set(0, 'currentfigure', app.UIAlign);
            %set(app.UIAlign, 'currentAxes', app.UIAxes);
            tiltThreshold = bench.config.pupillTipTiltThresholdManual;
	    pupThreshold = bench.config.pupillCenteringThreshold;
            phi = naomi.measure.phase(bench);
            imagesc(app.UIAxes, phi);
            hold(app.UIAxes, 'on');
            alpha = linspace(0,2*pi, 50);
            r1 = bench.sizePix(bench.config.fullPupillDiameter/2);
            r2 = bench.sizePix(bench.config.ztcPupillDiameter/2);
            r0 = bench.nSubAperture/2;
            plot(app.UIAxes,r1*cos(alpha)+r0, r1*sin(alpha)+r0, 'k:');
            plot(app.UIAxes,r2*cos(alpha)+r0, r2*sin(alpha)+r0, 'k:');
            hold(app.UIAxes, 'off');
            
            %xlabel(app.UIAxes,''); ylabel(app.UIAxes,'');
            %imagesc(phi);
            [nX,nY] = size(phi);
            xlim(app.UIAxes, [1,nX]);
            ylim(app.UIAxes, [1,nY]);
            %plot(app.UIAxes, [1,2,3,4,3,6]);
            [dX,dY,dTip,dTilt,dFoc] = naomi.measure.missalignment(bench, phi);
            title(app.UIAxes,{ datestr(now,'dd-mmm-yyyy HH:MM:SS.FFF'),
               sprintf('x=%.3fmm  y=%.3fmm  tip=%.2f  tilt=%.2f',...
                       dX*1e3, dY*1e3, dTip, dTilt)
               });

           app.TipGauge.Value = dTip;
           app.TiltGauge.Value = dTilt;
           app.XGauge.Value = dX*1000;
           app.YGauge.Value = dY*1000;
           app.XValue.Text = sprintf('%.4f',dX*1000);
           app.YValue.Text = sprintf('%.4f',dY*1000);
           app.TipValue.Text  = sprintf('%.4f',dTip);
           app.TiltValue.Text = sprintf('%.4f',dTilt);
           %if ((dX^2+dY^2)<= pupThreshold^2)
           test =0;
           if ((abs(dX)<= pupThreshold) && (abs(dY)<= pupThreshold))
               app.XYLamp.Color = 'green';
               test = test + 1;
           else
               app.XYLamp.Color = 'red';
           end
           %if ((dTip^2+dTilt^2) <= tiltThreshold^2)
           if (abs(dTip) <= tiltThreshold) && (abs(dTilt) <= tiltThreshold)
               app.TipTiltLamp.Color = 'green';
               test = test + 1;
           else
               app.TipTiltLamp.Color = 'red';
           end
           
           if test==2
               app.OKButton.Enable = 'on';
           else
               app.OKButton.Enable = 'off';
           end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global bench
            app.bench = bench;
            app.appTimer = timer('Period',0.7,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.appTimer.TimerFcn = @app.refresh;
            start(app.appTimer);
            
            tiltThreshold = bench.config.pupillTipTiltThresholdManual;
	    pupThreshold = bench.config.pupillCenteringThreshold*1000;
            
            app.TipGauge.ScaleColors = {'red', 'green', 'red'};
            app.TipGauge.ScaleColorLimits = [-10 -tiltThreshold ; -tiltThreshold tiltThreshold ; tiltThreshold 10];
            app.TiltGauge.ScaleColors = {'red', 'green', 'red'};
            app.TiltGauge.ScaleColorLimits = [-10 -tiltThreshold ; -tiltThreshold tiltThreshold ; tiltThreshold 10];
            
            app.XGauge.ScaleColors = {'red', 'green', 'red'};
            app.XGauge.ScaleColorLimits = [-10 -pupThreshold ; -pupThreshold pupThreshold ; pupThreshold 10];
            app.YGauge.ScaleColors = {'red', 'green', 'red'};
            app.YGauge.ScaleColorLimits = [-10 -pupThreshold ; -pupThreshold pupThreshold ; pupThreshold 10];
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            stop(app.appTimer);
            if app.bench.has('dm')
                % if the dm is running, after alignment measure 
                % the center actuator position and pixel scale 
                % and configure it into the bench
                IFCData = naomi.measure.IFC(app.bench);
                naomi.config.IFC(app.bench,IFC);
                [xScale, yScale] = naomi.measure.pixelScale(app.bench);
                naomi.config.pixelScale(app.bench, xScale, yScale);
            end
            pause(0.5);
            close(app.UIAlign);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             stop(app.appTimer);
             pause(0.5);
             close(app.UIAlign);
        end

        % Button pushed function: TipPLus
        function TipPLusButtonPushed(app, event)
            bench = app.bench;
            if bench.config.rXOrder == 2
                bench.gimbal.rX.moveByArcsec(bench.config.rXSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            else
                bench.gimbal.rY.moveByArcsec(bench.config.rYSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            end
        end

        % Button pushed function: TipMinus
        function TipMinusButtonPushed(app, event)
            bench = app.bench;
            if bench.config.rXOrder == 2
                bench.gimbal.rX.moveByArcsec(-bench.config.rXSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            else
                bench.gimbal.rY.moveByArcsec(-bench.config.rYSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            end
        end

        % Button pushed function: TiltPlus
        function TiltPlusButtonPushed(app, event)
            bench = app.bench;
            if bench.config.rXOrder == 3
                bench.gimbal.rX.moveByArcsec(bench.config.rXSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            else
                bench.gimbal.rY.moveByArcsec(bench.config.rYSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            end
        end

        % Button pushed function: TiltMinus
        function TiltMinusButtonPushed(app, event)
            bench = app.bench;
            if bench.config.rXOrder == 3
                bench.gimbal.rX.moveByArcsec(-bench.config.rXSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            else
                bench.gimbal.rY.moveByArcsec(-bench.config.rYSign * app.EditField.Value*4/2*1e-6/bench.config.fullPupillDiameter / 5e-6);
            end
        end

        % Button pushed function: TipHome
        function TipHomeButtonPushed(app, event)
            if app.bench.config.rXOrder == 2
                app.bench.gimbal.rX.moveToZero();
            else
                app.bench.gimbal.rY.moveToZero();
            end
        end

        % Button pushed function: TiltHome
        function TiltHomeButtonPushed(app, event)
            if app.bench.config.rXOrder == 3
                app.bench.gimbal.rX.moveToZero();
            else
                app.bench.gimbal.rY.moveToZero();
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAlign = uifigure;
            app.UIAlign.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIAlign);
            title(app.UIAxes, 'Title')
            app.TipGaugeLabel = uilabel(app.UIAlign);
            app.TipGaugeLabel.Text = 'Tip';
            app.TipGauge = uigauge(app.UIAlign, 'semicircular');
            app.TipGauge.Limits = [-3 3];
            app.TiltGaugeLabel = uilabel(app.UIAlign);
            app.TiltGaugeLabel.Text = 'Tilt';
            app.TiltGauge = uigauge(app.UIAlign, 'semicircular');
            app.TiltGauge.Limits = [-3 3];
            app.XYLampLabel = uilabel(app.UIAlign);
            app.XYLampLabel.Text = 'X/Y';
            app.XYLamp = uilamp(app.UIAlign);
            app.TipTiltLampLabel = uilabel(app.UIAlign);
            app.TipTiltLampLabel.Text = 'TipTilt';
            app.TipTiltLamp = uilamp(app.UIAlign);
            app.XGauge = uigauge(app.UIAlign, 'linear');
            app.XGauge.Limits = [-0.25 0.25];
            app.XGauge.MajorTicks = [-0.25 -0.15 -0.05 0.05 0.15 0.25];
            app.YGauge = uigauge(app.UIAlign, 'linear');
            app.YGauge.Limits = [-0.25 0.25];
            app.OKButton = uibutton(app.UIAlign, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIAlign, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.TipValue = uilabel(app.UIAlign);
            app.TipValue.Text = '0.0';
            app.TiltValue = uilabel(app.UIAlign);
            app.TiltValue.Text = '0.0';
            app.XValue = uilabel(app.UIAlign);
            app.XValue.Text = '0.0';
            app.YValue = uilabel(app.UIAlign);
            app.YValue.Text = '0.0';
            app.MotorsPanel = uipanel(app.UIAlign);
            app.MotorsPanel.Title = 'Motors';
            app.TipPLus = uibutton(app.MotorsPanel, 'push');
            app.TipPLus.ButtonPushedFcn = createCallbackFcn(app, @TipPLusButtonPushed, true);
            app.TipPLus.Text = '+';
            app.TipMinus = uibutton(app.MotorsPanel, 'push');
            app.TipMinus.ButtonPushedFcn = createCallbackFcn(app, @TipMinusButtonPushed, true);
            app.TipMinus.Text = '-';
            app.TiltMinus = uibutton(app.MotorsPanel, 'push');
            app.TiltMinus.ButtonPushedFcn = createCallbackFcn(app, @TiltMinusButtonPushed, true);
            app.TiltMinus.Text = '-';
            app.TiltPlus = uibutton(app.MotorsPanel, 'push');
            app.TiltPlus.ButtonPushedFcn = createCallbackFcn(app, @TiltPlusButtonPushed, true);
            app.TiltPlus.Text = '+';
            app.EditField = uieditfield(app.MotorsPanel, 'numeric');
            app.EditField.Limits = [0.001 50];
            app.EditField.Value = 0.1;
            app.AutoAlignButton = uibutton(app.MotorsPanel, 'push');
            app.AutoAlignButton.Text = 'Auto Align';
            app.StopButton = uibutton(app.MotorsPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.TipHome = uibutton(app.MotorsPanel, 'push');
            app.TipHome.ButtonPushedFcn = createCallbackFcn(app, @TipHomeButtonPushed, true);
            app.TipHome.Text = 'Home';
            app.TiltHome = uibutton(app.MotorsPanel, 'push');
            app.TiltHome.ButtonPushedFcn = createCallbackFcn(app, @TiltHomeButtonPushed, true);
            app.TiltHome.Text = 'Home';
        end
    end

    methods (Access = public)

        % Construct app
        function app = testgui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIAlign)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAlign)
        end
    end
end