classdef ztcExplorerGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZtCExplorerUIFigure      matlab.ui.Figure
        CloseButton              matlab.ui.control.Button
        DataInfo                 matlab.ui.control.Label
        DMPanel                  matlab.ui.container.Panel
        UIdm                     matlab.ui.control.UIAxes
        SaturationLampLabel      matlab.ui.control.Label
        SaturationLamp           matlab.ui.control.Lamp
        NSaturated               matlab.ui.control.Label
        FirstSaturatedatLabel    matlab.ui.control.Label
        FirstSaturatedAmplitude  matlab.ui.control.Label
        ZernikePanel             matlab.ui.container.Panel
        PreviousZernike          matlab.ui.control.Button
        NextZernike              matlab.ui.control.Button
        UITheoriticalPhase       matlab.ui.control.UIAxes
        UIZernikeInfo            matlab.ui.control.UIAxes
        ZernikeEditFieldLabel    matlab.ui.control.Label
        ZernikeNumber            matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel  matlab.ui.control.Label
        Amplitude                matlab.ui.control.NumericEditField
        ZerniketoCommandPanel    matlab.ui.container.Panel
        UIztc                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        ZtCData; % The ZtC Matrix as naomi.data.ZtC
        BiasData; % TheDM bias as naomi.data.dmVector
        curentZernike = 1;
    end

    methods (Access = public)
    
        function refresh(app)
            app.ZernikeNumber.Value = app.curentZernike;
            
            app.ZtCData.plot(app.UIztc);
            
            dmv = app.ZtCData.data(app.curentZernike, ':');
            
            app.FirstSaturatedAmplitude.Text = sprintf('%.2f',1./max(abs(dmv)));
            
            dmCommandData = app.ZtCData.dmCommandData(app.curentZernike, app.Amplitude.Value);
            dmCommandData.plotImage(app.UIdm);
            app.NSaturated.Text = sprintf('%d', dmCommandData.nSaturated);
            if dmCommandData.nSaturated
                app.SaturationLamp.Color = [1,0,0];
            else
                app.SaturationLamp.Color = [0,1,0];
            end
            
            zernikeData = app.ZtCData.zernike(app.curentZernike);
            zernikeData.plotPhase(app.UITheoriticalPhase);
            zernikeData.plotInfo(app.UIZernikeInfo);
            
            app.DataInfo.Text = app.ZtCData.shortInfo;
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ZtCData)
            app.ZtCData = ZtCData;
            
            [nZernike,~] = size(app.ZtCData.data);
            app.curentZernike = app.ZtCData.firstZernike;
            
            app.ZernikeNumber.Value = app.curentZernike;
            
            app.ZernikeNumber.Limits = [app.ZtCData.firstZernike,app.ZtCData.lastZernike];
            app.refresh();
            app.ZernikeNumber.Value = app.curentZernike;
           
         
        end

        % Value changed function: ZernikeNumber
        function ZernikeNumberValueChanged(app, event)
            value = app.ZernikeNumber.Value;
            app.curentZernike = value;
            app.refresh();
        end

        % Button pushed function: PreviousZernike
        function PreviousZernikeButtonPushed(app, event)
            l = app.ZernikeNumber.Limits;
            if app.curentZernike>l(1);
                app.curentZernike = app.curentZernike -1;
                app.refresh();
            end
        end

        % Button pushed function: NextZernike
        function NextZernikeButtonPushed(app, event)
            l = app.ZernikeNumber.Limits;
            
            if app.curentZernike<l(2);
                app.curentZernike = app.curentZernike +1;
                app.refresh();
            end
        end

        % Value changed function: Amplitude
        function AmplitudeValueChanged(app, event)

            app.refresh();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.ZtCExplorerUIFigure );
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZtCExplorerUIFigure = uifigure;
            app.ZtCExplorerUIFigure.Name = 'ZtC Explorer';
            app.CloseButton = uibutton(app.ZtCExplorerUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DataInfo = uilabel(app.ZtCExplorerUIFigure);
            app.DataInfo.Text = '--------';
            app.DMPanel = uipanel(app.ZtCExplorerUIFigure);
            app.DMPanel.Title = 'DM';
            app.UIdm = uiaxes(app.DMPanel);
            app.SaturationLampLabel = uilabel(app.DMPanel);
            app.SaturationLampLabel.Text = 'Saturation';
            app.SaturationLamp = uilamp(app.DMPanel);
            app.NSaturated = uilabel(app.DMPanel);
            app.FirstSaturatedatLabel = uilabel(app.DMPanel);
            app.FirstSaturatedatLabel.Text = 'First Saturated at';
            app.FirstSaturatedAmplitude = uilabel(app.DMPanel);
            app.FirstSaturatedAmplitude.Text = 'Label2';
            app.ZernikePanel = uipanel(app.ZtCExplorerUIFigure);
            app.ZernikePanel.Title = 'Zernike';
            app.PreviousZernike = uibutton(app.ZernikePanel, 'push');
            app.PreviousZernike.ButtonPushedFcn = createCallbackFcn(app, @PreviousZernikeButtonPushed, true);
            app.PreviousZernike.Text = '-';
            app.NextZernike = uibutton(app.ZernikePanel, 'push');
            app.NextZernike.ButtonPushedFcn = createCallbackFcn(app, @NextZernikeButtonPushed, true);
            app.NextZernike.Text = '+';
            app.UITheoriticalPhase = uiaxes(app.ZernikePanel);
            app.UIZernikeInfo = uiaxes(app.ZernikePanel);
            app.ZernikeEditFieldLabel = uilabel(app.ZernikePanel);
            app.ZernikeEditFieldLabel.Text = 'Zernike';
            app.ZernikeNumber = uieditfield(app.ZernikePanel, 'numeric');
            app.ZernikeNumber.ValueDisplayFormat = '%.0f';
            app.ZernikeNumber.ValueChangedFcn = createCallbackFcn(app, @ZernikeNumberValueChanged, true);
            app.ZernikeNumber.Value = 1;
            app.AmplitudeEditFieldLabel = uilabel(app.ZernikePanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.Amplitude = uieditfield(app.ZernikePanel, 'numeric');
            app.Amplitude.ValueChangedFcn = createCallbackFcn(app, @AmplitudeValueChanged, true);
            app.Amplitude.Value = 1;
            app.ZerniketoCommandPanel = uipanel(app.ZtCExplorerUIFigure);
            app.ZerniketoCommandPanel.Title = 'Zernike to Command';
            app.UIztc = uiaxes(app.ZerniketoCommandPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ztcExplorerGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZtCExplorerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZtCExplorerUIFigure)
        end
    end
end